classdef HkcPick_4ClusteringAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        SaveClusteringResultsMenu   matlab.ui.container.Menu
        GenerateClusteringDataMenu  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        ClusteringSta4Label         matlab.ui.control.Label
        ClusteringSta3Label         matlab.ui.control.Label
        ClusteringSta2Label         matlab.ui.control.Label
        ClusteringSta1Label         matlab.ui.control.Label
        CentreStaLabel              matlab.ui.control.Label
        SAVEButton                  matlab.ui.control.Button
        ResetFigSizeButton          matlab.ui.control.Button
        EnlargeFigSizeButton        matlab.ui.control.Button
        StnumSpinner                matlab.ui.control.Spinner
        ProjectInformationLabel     matlab.ui.control.Label
        TotalNumLabel               matlab.ui.control.Label
        GotoButton                  matlab.ui.control.Button
        allbefore                   matlab.ui.control.UIAxes
        hkbefore                    matlab.ui.control.UIAxes
        Ps_1                        matlab.ui.control.UIAxes
        M1_1                        matlab.ui.control.UIAxes
        M2_1                        matlab.ui.control.UIAxes
        Ps_2                        matlab.ui.control.UIAxes
        Ps_3                        matlab.ui.control.UIAxes
        M1_3                        matlab.ui.control.UIAxes
        Hk_1                        matlab.ui.control.UIAxes
        Hk_2                        matlab.ui.control.UIAxes
        Hk_3                        matlab.ui.control.UIAxes
        Hk_4                        matlab.ui.control.UIAxes
        Hk_5                        matlab.ui.control.UIAxes
        M2_5                        matlab.ui.control.UIAxes
        M1_5                        matlab.ui.control.UIAxes
        Ps_5                        matlab.ui.control.UIAxes
        M2_4                        matlab.ui.control.UIAxes
        M1_4                        matlab.ui.control.UIAxes
        Ps_4                        matlab.ui.control.UIAxes
        M2_3                        matlab.ui.control.UIAxes
        M2_2                        matlab.ui.control.UIAxes
        M1_2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Hkc Pick program Info
        Hkc_pick_dir      % Location for Hkc_Pick results
        Hkc_pick_app_dir  % Location for Hkc_Pick raw data
        projectname       % Name of this Hkc-Pick job
        Hkc_data_dir_table % Hkc Results with no picking
        figsuffix='.png'
        run1_dir          % Location for run1
        run2_dir          % Location for run2
        run3_dir          % Location for run3
        run4_dir          % Location for run4
        run5_dir          % Location for run5

        % Pick Control
        inputdir_index      % index of input directory
        pick_index_now = 0  % index of station to pick now

        pick_index_next     % index of station to pick next
        is_saved = false    % whether previous pick is saved
        is_savedall = false % whether saved all picks to file
        is_genfile = false  % whether generated plotting file

        UP=0.01           % Cutting HA figures
        DW=0.435          % UP,DW,LF,RF >0
        LF=0.03           % UP,DW,LF,RF <=1
        RT=0.8

        UPhk=0.07         % Cutting hk figures
        DWhk=0.35
        LFhk=0.01
        RThk=0.4

        UPbefore=0.07     % Cutting allbefore figures
        DWbefore=0.98
        LFbefore=0.001
        RTbefore=0.5

        % Hkc Pick Data
        Hkc_allstatistics % Output of HkcPick_PerturbationByInitCond
        Hkc_alltextdata   % Output of HkcPick_AppReadData
        HkcPick_tableall  % a table that saves all data

    end

    properties (Access = public)
        pick_table_now      % table of station to pick now
    end


    methods (Access = private)

        function  HkcPickShowFig(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UP*a):floor(app.DW*a),ceil(app.LF*b):floor(app.RT*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFighk(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPhk*a):floor(app.DWhk*a),ceil(app.LFhk*b):floor(app.RThk*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFigallbefore(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPbefore*a):floor(app.DWbefore*a),ceil(app.LFbefore*b):floor(app.RTbefore*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  ReNewFigures(app)
            %% STA1
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_1, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_1, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_1, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_1, filename);

            %% STA2
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_2, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_2, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_2, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_2, filename);

            %% STA3
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_3, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_3, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_3, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_3, filename);

            %% STA4
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_4, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_4, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_4, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_4, filename);

            %% STA5
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_5, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_5, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_5, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_5, filename);

            %%Allbefore
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_allbefore',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFigallbefore(app, app.allbefore, filename);

            %%hkbefore
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_hk_before',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.hkbefore, filename);
        end

        function EnlargeHA(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargehk(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargebefore(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.03;
            figposi(4)=figposi(4)*1.4;
            figregion.InnerPosition=figposi;
        end

        function EnlargeFigs(app)
            EnlargeHA(app, app.Ps_1);EnlargeHA(app, app.M1_1);EnlargeHA(app, app.M2_1);
            EnlargeHA(app, app.Ps_2);EnlargeHA(app, app.M1_2);EnlargeHA(app, app.M2_2);
            EnlargeHA(app, app.Ps_3);EnlargeHA(app, app.M1_3);EnlargeHA(app, app.M2_3);
            EnlargeHA(app, app.Ps_4);EnlargeHA(app, app.M1_4);EnlargeHA(app, app.M2_4);
            EnlargeHA(app, app.Ps_5);EnlargeHA(app, app.M1_5);EnlargeHA(app, app.M2_5);

            Enlargehk(app,app.Hk_1);Enlargehk(app,app.Hk_2);Enlargehk(app,app.Hk_3);
            Enlargehk(app,app.Hk_4);Enlargehk(app,app.Hk_5);

            Enlargebefore(app, app.allbefore);
            Enlargehk(app,app.hkbefore);
        end

        
        function GetClusteringResults(app)
            
        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dir1)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;

            temp=split(dir1,filesep);
            app.projectname=string(temp(end));
            app.ProjectInformationLabel.Text=app.projectname;

            app.Hkc_pick_dir=dir1;
            cd(app.Hkc_pick_app_dir)

            % Get total station numbers
            try
                cd HkcPickAppData
                app.Hkc_pick_app_dir=pwd;
                if(exist(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'),"file")==0)
                    errordlg("Could not found AllHkcPickData.txt!",'Error Message','error')
                    return
                else
                    app.HkcPick_tableall=readtable(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'));
                    [l,~]=size(app.HkcPick_tableall);
                    app.TotalNumLabel.Text=strcat('/',num2str(l));
                    app.StnumSpinner.Limits=[1 l];
                    app.StnumSpinner.Value=1;
                end
            catch
                errordlg("HkcPickAppData directory doesn't exist!",'Error Message','error')
                return
            end


            % Read Absolute Path of Hkc-Pick Dir
            if(exist(fullfile(app.Hkc_pick_app_dir,'InputDir.txt'),"file")==0)
                errordlg("Could not found InputDir.txt!",'Error Message','error')
                return
            else
                app.Hkc_data_dir_table=readtable(fullfile(app.Hkc_pick_app_dir,'InputDir.txt'));
                app.Hkc_data_dir_table=renamevars(app.Hkc_data_dir_table,["Var1","Var2"],["dir_idx","dir_path"]);
            end
            
            % Write Start Records
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');
            fprintf(fileID,"%s %s\n",'[Start Run] HkcPick_4ClusteringAnalysis at: ',datestr(now,0));
            fclose(fileID);


        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            app.pick_index_next=app.StnumSpinner.Value;


        end

        % Button pushed function: EnlargeFigSizeButton
        function EnlargeFigSizeButtonPushed(app, event)
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
            EnlargeFigs(app);
        end

        % Button pushed function: ResetFigSizeButton
        function ResetFigSizeButtonPushed(app, event)
            % Reduce App window size to trigger auto adjustion
            ss = get(0,'ScreenSize');
            ss(1)=100;
            ss(2)=100;
            ss(3)=round(ss(3)/2);
            ss(4)=round(ss(4)/2);
            app.UIFigure.Position=ss;
            pause(0.7)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            [n,~]=size(app.HkcPick_tableall);
            idx4merge=nan;
            if(n~=0)
                for m=1:n
                    if(app.pick_index_now==app.HkcPick_tableall.sta_idx(m))
                        idx4merge=m;
                    end
                end
            end

            if(isnan(idx4merge))
                errordlg("Could not found Record!",'Error Message','error')
                return
            else
                % merge to existed line
                % delete old line
                app.HkcPick_tableall(idx4merge,:)=app.pick_table_now;
            end
            % sort table
            [app.HkcPick_tableall,~] = sortrows(app.HkcPick_tableall,'sta_idx');
            app.is_saved=true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');

            if(~app.is_savedall || ~app.is_genfile)
                msg = ["Plese Save All Results and Generate Plotting Data";...
                    strcat("Results Saved :",num2str(app.is_savedall));...
                    strcat('Data Generated :',num2str(app.is_genfile))];
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...SAVEButtonPushed(app);
                    'Options',{'Forced Exit','Save Results','Generate Plotting Data','Cancel'}, ...
                    'DefaultOption',4,'CancelOption',3);
                switch selection
                    case 'Forced Exit'
                        delete(app.DialogApp)
                        delete(app)
                    case 'Save Results'
                        SaveHkcPickResultsMenuSelected(app);
                        if(app.is_genfile)
                            fprintf(fileID,"%s %s\n",'[End Run] HkcPick_4ClusteringAnalysis at: ',datestr(now,0));
                            fclose(fileID);
                            % Delete both apps
                            delete(app.DialogApp)
                            delete(app)
                            return
                        else
                            fclose(fileID);
                            return
                        end
                    case 'Generate Plotting Data'
                        GeneratePlottingDataMenuSelected(app);
                        if(app.is_savedall)
                            fprintf(fileID,"%s %s\n",'[End Run] HkcPick_4ClusteringAnalysis at: ',datestr(now,0));
                            fclose(fileID);
                            % Delete both apps
                            delete(app.DialogApp)
                            delete(app)
                            return
                        else
                            fclose(fileID);
                            return
                        end
                    case 'Cancel'
                        fclose(fileID);
                        return
                end
            else
                % Delete both apps
                fprintf(fileID,"%s %s\n",'[End Run] HkcPick_4ClusteringAnalysis at: ',datestr(now,0));
                fclose(fileID);
                delete(app.DialogApp)
                delete(app)
            end

        end

        % Menu selected function: SaveClusteringResultsMenu
        function SaveClusteringResultsMenuSelected(app, event)
            app.is_savedall=true;
            cd(app.Hkc_pick_app_dir)
            writetable(app.HkcPick_tableall,'AllHkcPickData.txt')
            copyfile('AllHkcPickData.txt',strcat('AllHkcPickData',datestr(now,'yymmdd_HHMMss'),'.txt'))
            movefile(strcat('AllHkcPickData',datestr(now,'yymmdd_HHMMss'),'.txt'),'DataBackUp')
        end

        % Menu selected function: GenerateClusteringDataMenu
        function GenerateClusteringDataMenuSelected(app, event)
            cd(app.Hkc_pick_dir)
            % Get Hk before
            idx=~isnan(app.HkcPick_tableall.Hafter);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','dH_clustering_auto','dkappa_clustering_auto','H_clustering','kappa_clustering'}),strcat(app.projectname,'_ClusteringAnalysisResult.txt'))
            app.is_genfile=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveClusteringResultsMenu = uimenu(app.Menu);
            app.SaveClusteringResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveClusteringResultsMenuSelected, true);
            app.SaveClusteringResultsMenu.Accelerator = 'S';
            app.SaveClusteringResultsMenu.Text = 'Save Clustering Results';
            app.GenerateClusteringDataMenu = uimenu(app.Menu);
            app.GenerateClusteringDataMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateClusteringDataMenuSelected, true);
            app.GenerateClusteringDataMenu.Accelerator = 'G';
            app.GenerateClusteringDataMenu.Text = 'Generate Clustering Data';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.M1_2 = uiaxes(app.GridLayout);
            app.M1_2.Visible = 'off';
            app.M2_2 = uiaxes(app.GridLayout);
            app.M2_2.Visible = 'off';
            app.M2_3 = uiaxes(app.GridLayout);
            app.M2_3.Visible = 'off';
            app.Ps_4 = uiaxes(app.GridLayout);
            app.Ps_4.Visible = 'off';
            app.M1_4 = uiaxes(app.GridLayout);
            app.M1_4.Visible = 'off';
            app.M2_4 = uiaxes(app.GridLayout);
            app.M2_4.Visible = 'off';
            app.Ps_5 = uiaxes(app.GridLayout);
            app.Ps_5.Visible = 'off';
            app.M1_5 = uiaxes(app.GridLayout);
            app.M1_5.Visible = 'off';
            app.M2_5 = uiaxes(app.GridLayout);
            app.M2_5.Visible = 'off';
            app.Hk_5 = uiaxes(app.GridLayout);
            app.Hk_5.Visible = 'off';
            app.Hk_4 = uiaxes(app.GridLayout);
            app.Hk_4.Visible = 'off';
            app.Hk_3 = uiaxes(app.GridLayout);
            app.Hk_3.Visible = 'off';
            app.Hk_2 = uiaxes(app.GridLayout);
            app.Hk_2.Visible = 'off';
            app.Hk_1 = uiaxes(app.GridLayout);
            app.Hk_1.Visible = 'off';
            app.M1_3 = uiaxes(app.GridLayout);
            app.M1_3.Visible = 'off';
            app.Ps_3 = uiaxes(app.GridLayout);
            app.Ps_3.Visible = 'off';
            app.Ps_2 = uiaxes(app.GridLayout);
            app.Ps_2.Visible = 'off';
            app.M2_1 = uiaxes(app.GridLayout);
            app.M2_1.Visible = 'off';
            app.M1_1 = uiaxes(app.GridLayout);
            app.M1_1.Visible = 'off';
            app.Ps_1 = uiaxes(app.GridLayout);
            app.Ps_1.Visible = 'off';
            app.hkbefore = uiaxes(app.GridLayout);
            app.hkbefore.Visible = 'off';
            app.allbefore = uiaxes(app.GridLayout);
            app.allbefore.Visible = 'off';
            app.GotoButton = uibutton(app.GridLayout, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Go to';
            app.TotalNumLabel = uilabel(app.GridLayout);
            app.TotalNumLabel.Text = 'TotalNum';
            app.ProjectInformationLabel = uilabel(app.GridLayout);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.StnumSpinner = uispinner(app.GridLayout);
            app.EnlargeFigSizeButton = uibutton(app.GridLayout, 'push');
            app.EnlargeFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFigSizeButtonPushed, true);
            app.EnlargeFigSizeButton.Tooltip = {'Enlarge the picture proportionally.'};
            app.EnlargeFigSizeButton.Text = 'Enlarge FigSize';
            app.ResetFigSizeButton = uibutton(app.GridLayout, 'push');
            app.ResetFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFigSizeButtonPushed, true);
            app.ResetFigSizeButton.Tooltip = {'When the image is enlarged too much, restore it to its original size.'};
            app.ResetFigSizeButton.Text = 'Reset FigSize';
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.CentreStaLabel = uilabel(app.GridLayout);
            app.CentreStaLabel.Text = 'CentreSta';
            app.ClusteringSta1Label = uilabel(app.GridLayout);
            app.ClusteringSta1Label.Text = 'ClusteringSta1';
            app.ClusteringSta2Label = uilabel(app.GridLayout);
            app.ClusteringSta2Label.Text = 'ClusteringSta2';
            app.ClusteringSta3Label = uilabel(app.GridLayout);
            app.ClusteringSta3Label.Text = 'ClusteringSta3';
            app.ClusteringSta4Label = uilabel(app.GridLayout);
            app.ClusteringSta4Label.Text = 'ClusteringSta4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_4ClusteringAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end