classdef HkcPick_2ContinuePick < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        SaveHkcPickResultsMenu    matlab.ui.container.Menu
        GeneratePlottingDataMenu  matlab.ui.container.Menu
        OpenPickAnalysisToolMenu  matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        SAVEButton                matlab.ui.control.Button
        ResetFigSizeButton        matlab.ui.control.Button
        EnlargeFigSizeButton      matlab.ui.control.Button
        Run2Button                matlab.ui.control.StateButton
        Run5Button                matlab.ui.control.StateButton
        Run4Button                matlab.ui.control.StateButton
        Run3Button                matlab.ui.control.StateButton
        Run1Button                matlab.ui.control.StateButton
        StnumSpinner              matlab.ui.control.Spinner
        ProjectInformationLabel   matlab.ui.control.Label
        TotalNumLabel             matlab.ui.control.Label
        GotoButton                matlab.ui.control.Button
        Hk_5                      matlab.ui.control.UIAxes
        Hk_4                      matlab.ui.control.UIAxes
        Hk_3                      matlab.ui.control.UIAxes
        Hk_2                      matlab.ui.control.UIAxes
        Hk_1                      matlab.ui.control.UIAxes
        M1_3                      matlab.ui.control.UIAxes
        Ps_3                      matlab.ui.control.UIAxes
        Ps_2                      matlab.ui.control.UIAxes
        M2_1                      matlab.ui.control.UIAxes
        M1_1                      matlab.ui.control.UIAxes
        Ps_1                      matlab.ui.control.UIAxes
        hkbefore                  matlab.ui.control.UIAxes
        allbefore                 matlab.ui.control.UIAxes
        M2_5                      matlab.ui.control.UIAxes
        M1_5                      matlab.ui.control.UIAxes
        Ps_5                      matlab.ui.control.UIAxes
        M2_4                      matlab.ui.control.UIAxes
        M1_4                      matlab.ui.control.UIAxes
        Ps_4                      matlab.ui.control.UIAxes
        M2_3                      matlab.ui.control.UIAxes
        M2_2                      matlab.ui.control.UIAxes
        M1_2                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Hkc Pick program Info
        Hkc_pick_dir      % Location for Hkc_Pick results
        Hkc_pick_app_dir  % Location for Hkc_Pick raw data
        projectname       % Name of this Hkc-Pick job
        Hkc_init_data_dir % Hkc Results with no picking
        figsuffix='.png'
        run1_dir          % Location for run1
        run2_dir          % Location for run2
        run3_dir          % Location for run3
        run4_dir          % Location for run4
        run5_dir          % Location for run5

        % Pick Control
        inputdir_index      % index of input directory
        pick_index_now = 0  % index of station to pick now

        pick_index_next     % index of station to pick next
        is_saved = false    % whether previous pick is saved
        is_marked = false   % whether previous pick is marked
        is_savedall = false % whether saved all picks to file
        is_genfile = false  % whether generated plotting file

        UP=0.01           % Cutting HA figures
        DW=0.435          % UP,DW,LF,RF >0
        LF=0.03           % UP,DW,LF,RF <=1
        RT=0.8

        UPhk=0.07         % Cutting hk figures
        DWhk=0.35
        LFhk=0.01
        RThk=0.4

        UPbefore=0.07     % Cutting allbefore figures
        DWbefore=0.98
        LFbefore=0.001
        RTbefore=0.5

        % Hkc Pick Data
        Hkc_allstatistics % Output of HkcPick_PerturbationByInitCond
        Hkc_alltextdata   % Output of HkcPick_AppReadData
        plan_selected     % Selected plan
        HkcPick_tableall  % a table that saves all data

        % Interactive HkcPick Analysis Dialog
        DialogApp

    end

    properties (Access = public)
        pick_table_now      % table of station to pick now
    end


    methods (Access = private)

        function  HkcPickShowFig(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UP*a):floor(app.DW*a),ceil(app.LF*b):floor(app.RT*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFighk(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPhk*a):floor(app.DWhk*a),ceil(app.LFhk*b):floor(app.RThk*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFigallbefore(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPbefore*a):floor(app.DWbefore*a),ceil(app.LFbefore*b):floor(app.RTbefore*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  ReNewFigures(app)
            %% Run1
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_1, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_1, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_1, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run1_dir,'run1_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_1, filename);

            %%Run2
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_2, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_2, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_2, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run2_dir,'run2_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_2, filename);

            %%Run3
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_3, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_3, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_3, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_3, filename);

            %%Run4
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_4, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_4, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_4, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run4_dir,'run4_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_4, filename);

            %%Run5
            % Ps
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA1',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_5, filename);
            % M1
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA2',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_5, filename);
            % M2
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_0_HA3',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_5, filename);
            % hk_after
            filename=fullfile(app.Hkc_init_data_dir,app.run5_dir,'run5_hk_after',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_5, filename);

            %%Allbefore
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_allbefore',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFigallbefore(app, app.allbefore, filename);

            %%hkbefore
            filename=fullfile(app.Hkc_init_data_dir,app.run3_dir,'run3_hk_before',strcat(num2str(app.pick_index_now),app.figsuffix));
            HkcPickShowFighk(app, app.hkbefore, filename);
        end

        function EnlargeHA(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargehk(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargebefore(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.03;
            figposi(4)=figposi(4)*1.4;
            figregion.InnerPosition=figposi;
        end

        function EnlargeFigs(app)
            EnlargeHA(app, app.Ps_1);EnlargeHA(app, app.M1_1);EnlargeHA(app, app.M2_1);
            EnlargeHA(app, app.Ps_2);EnlargeHA(app, app.M1_2);EnlargeHA(app, app.M2_2);
            EnlargeHA(app, app.Ps_3);EnlargeHA(app, app.M1_3);EnlargeHA(app, app.M2_3);
            EnlargeHA(app, app.Ps_4);EnlargeHA(app, app.M1_4);EnlargeHA(app, app.M2_4);
            EnlargeHA(app, app.Ps_5);EnlargeHA(app, app.M1_5);EnlargeHA(app, app.M2_5);

            Enlargehk(app,app.Hk_1);Enlargehk(app,app.Hk_2);Enlargehk(app,app.Hk_3);
            Enlargehk(app,app.Hk_4);Enlargehk(app,app.Hk_5);

            Enlargebefore(app, app.allbefore);
            Enlargehk(app,app.hkbefore);
        end

        function runPickAnalysisTool(app)
            % Disable Plot Options button while dialog is open
            app.OpenPickAnalysisToolMenu.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp = HkcPick_TextAnalysis(app, app.pick_table_now, app.Hkc_init_data_dir);
        end
    end

    methods (Access = public)

        function PickAnalysisChangeRun(app,selectedtype)
            switch selectedtype
                case 'Run1'
                    app.Run1Button.Value=1;
                    app.Run2Button.Value=0;
                    app.Run3Button.Value=0;
                    app.Run4Button.Value=0;
                    app.Run5Button.Value=0;
                    app.plan_selected=1;
                    app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                    % Update RecordTable
                    app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                    assignin("base","pick_table",app.pick_table_now)
                    app.is_saved=false;
                    UpdateResultTable(app.DialogApp)
                    UpdateAprioriTable(app.DialogApp)
                case 'Run2'
                    app.Run1Button.Value=0;
                    app.Run2Button.Value=1;
                    app.Run3Button.Value=0;
                    app.Run4Button.Value=0;
                    app.Run5Button.Value=0;
                    app.plan_selected=2;
                    app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                    % Update RecordTable
                    app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                    assignin("base","pick_table",app.pick_table_now)
                    app.is_saved=false;
                    UpdateResultTable(app.DialogApp)
                    UpdateAprioriTable(app.DialogApp)
                case 'Run3'
                    app.Run1Button.Value=0;
                    app.Run2Button.Value=0;
                    app.Run3Button.Value=1;
                    app.Run4Button.Value=0;
                    app.Run5Button.Value=0;
                    app.plan_selected=3;
                    app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                    % Update RecordTable
                    app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                    assignin("base","pick_table",app.pick_table_now)
                    app.is_saved=false;
                    UpdateResultTable(app.DialogApp)
                    UpdateAprioriTable(app.DialogApp)
                case 'Run4'
                    app.Run1Button.Value=0;
                    app.Run2Button.Value=0;
                    app.Run3Button.Value=0;
                    app.Run4Button.Value=1;
                    app.Run5Button.Value=0;
                    app.plan_selected=4;
                    app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                    % Update RecordTable
                    app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                    assignin("base","pick_table",app.pick_table_now)
                    app.is_saved=false;
                    UpdateResultTable(app.DialogApp)
                    UpdateAprioriTable(app.DialogApp)
                case 'Run5'
                    app.Run1Button.Value=0;
                    app.Run2Button.Value=0;
                    app.Run3Button.Value=0;
                    app.Run4Button.Value=0;
                    app.Run5Button.Value=1;
                    app.plan_selected=5;
                    app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                    % Update RecordTable
                    app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                    assignin("base","pick_table",app.pick_table_now)
                    app.is_saved=false;
                    UpdateResultTable(app.DialogApp)
                    UpdateAprioriTable(app.DialogApp)
            end
        end

        function GetNowTableFromDialogApp(app)
            app.pick_table_now=app.DialogApp.pick_table_now;
            app.is_marked=true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dirdata, dirpick)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
            % Get location for Hkc data
            app.Hkc_init_data_dir=dirdata;
            % get total station numbers
            cd(app.Hkc_init_data_dir);
            datainfo=ls('*.txt');
            % Read datainfo file
            try
                lines=readlines(datainfo,"EmptyLineRule","skip");
            catch
                errordlg(["Error in reading *_Raw.txt file when starting HkcPick_2ContinuePick!"; ...
                    "Check whether there is only one text file (*_Raw.txt) under the data_init folder "],'Error Message','error')
                return
            end
            % Get total station numbers
            [l,~]=size(lines);
            app.TotalNumLabel.Text=strcat('/',num2str(l));
%             app.StnumSpinner.Limits=[1 l];
%             app.StnumSpinner.Value=1;
            if(l>1)
                app.StnumSpinner.Limits=[1 l];
                app.StnumSpinner.Value=1;
            elseif(l==1)
                app.StnumSpinner.Limits=[0 1];
                app.StnumSpinner.Value=1;
            else
                errordlg("No Record is founded in *_Raw.txt!",'Error Message','error')
                return
            end

            % Get Old Pick
            temp=split(dirdata,filesep);
            app.projectname=strcat(string(temp(end)),'_Pick');
            app.ProjectInformationLabel.Text=app.projectname;

            app.Hkc_pick_dir=dirpick;
            
            try
                cd(app.Hkc_pick_dir)
                cd HkcPickAppData
                app.Hkc_pick_app_dir=pwd;
            catch
                errordlg("Error in Entering HkcPickAppData! Please Check whether it exists!",'Error Message','error')
                return
            end

            % Write Start Records
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');
            fprintf(fileID,"%s %s\n",'[Start Run] HkcPick_2ContinuePick at: ',datestr(now,0));
            fclose(fileID);
            % Write Input Dir Info
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"InputDir.txt"),'a+');
            try
                lines=readlines(fullfile(app.Hkc_pick_app_dir,"InputDir.txt"),"EmptyLineRule","skip");
                [l,~]=size(lines);
                %frewind(fileID);
                if(l==0)
                    % Write Current Input Dir to New File
                    app.inputdir_index=l+1;
                    fprintf(fileID,"%-4d %-200s\n",app.inputdir_index,app.Hkc_init_data_dir);
                else
                    % Check Whether InputDir.txt contains app.init_data_dir
                    check=contains(lines,app.Hkc_init_data_dir);
                    if(sum(check)==0)
                        % InputDir.txt doesn't contain app.init_data_dir
                        app.inputdir_index=l+1;
                        fprintf(fileID,"%-4d %-200s\n",app.inputdir_index,app.Hkc_init_data_dir);
                    elseif(sum(check)==1)
                        % InputDir.txt contains app.init_data_dir
                        app.inputdir_index=str2double(extractBefore(lines(check),5));
                    else
                        errordlg(["Error in reading InputDir.txt file!",...
                            strcat("More than 1 Records of ",app.Hkc_init_data_dir," founded")],'Error Message','error')
                    end
                end
            catch
                errordlg("Error in reading/writing InputDir.txt file!",'Error Message','error')
            end
            fclose(fileID);

            % Goto Data Directory and start to read data
            cd(app.Hkc_init_data_dir)
            app.run1_dir=string(ls('*Run1'));
            app.run2_dir=string(ls('*Run2'));
            app.run3_dir=string(ls('*Run3'));
            app.run4_dir=string(ls('*Run4'));
            app.run5_dir=string(ls('*Run5'));

            % Run Initialize Script
            d = uiprogressdlg(app.UIFigure,'Title','Initalizing, please wait...',...
                'Message','Calculating normalization constant...','Cancelable','on');
            %cd(app.Hkc_pick_app_dir)
            if(exist(fullfile(app.Hkc_pick_app_dir,'STDall.txt'),"file")==0)
                app.Hkc_allstatistics = HkcPick_PerturbationByInitCond(app.Hkc_init_data_dir,app.Hkc_pick_dir,[app.run1_dir,app.run2_dir,app.run3_dir,app.run4_dir,app.run5_dir]);
                writetable(app.Hkc_allstatistics,fullfile(app.Hkc_pick_app_dir,'STDall.txt'))
            else
                app.Hkc_allstatistics = readtable(fullfile(app.Hkc_pick_app_dir,'STDall.txt'),'FileType','Text');
            end
            d.Value=.33;
            pause(0.3)

            d.Message='Reading Hkc text results...';
            app.Hkc_alltextdata=HkcPick_AppReadData(app.Hkc_init_data_dir,[app.run1_dir,app.run2_dir,app.run3_dir,app.run4_dir,app.run5_dir]);
            d.Value=.67;
            pause(0.3)

            d.Message='Initializing internal tables...';
            app.HkcPick_tableall=HkcPick_GenerateTableHeader;
            EnlargeFigs(app);
            pause(0.3)

            d.Value=1;
            d.Title='Initialization succeeded!';
            d.Message='Press Go to button to start';
            pause(1)

            close(d)

            % Open HkcPickTextDataAnalysis Tool
            runPickAnalysisTool(app)
            
            % Open One Pick Job
            if(exist(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'),"file")==0)
                errordlg("Could not found AllHkcPickData.txt!",'Error Message','error')
                return
            else
                %Read Existed Data
                opts = detectImportOptions(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'));
                opts = setvartype(opts,{'stnm','note'},'string');
                app.HkcPick_tableall=readtable(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'),opts);
                [l,~]=size(app.HkcPick_tableall);
                app.pick_index_now=l;
                app.pick_index_next=min(l+1,app.StnumSpinner.Limits(2));
                if(app.pick_index_next==app.StnumSpinner.Limits(2))
                    app.StnumSpinner.Value=1;
                else
                app.is_saved=true;
                app.StnumSpinner.Value=app.pick_index_next;
                GotoButtonPushed(app);
                end
            end


        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            app.pick_index_next=app.StnumSpinner.Value;
            app.SAVEButton.Enable='on';
            app.Run1Button.Enable='on';
            app.Run2Button.Enable='on';
            app.Run3Button.Enable='on';
            app.Run4Button.Enable='on';
            app.Run5Button.Enable='on';

            if(app.pick_index_next==app.pick_index_now)
                % no change and do nothing
                return
            end

            % Check whether record exists
            if(isempty(find(app.HkcPick_tableall.sta_idx==app.pick_index_next, 1)))
                % RECORD DOES NOT EXIST
                % for the first station, just go on
                % for the following stations, ask whether save previous pick
                % if previous pick is not saved
                if(app.pick_index_next~=1)
                    if(~app.is_saved)
                        msg = strcat('Pick for :',num2str(app.pick_index_now),'_',...
                            app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now} ,' is not saved. Save now?');
                        title = 'Confirm Save';
                        selection = uiconfirm(app.UIFigure,msg,title, ...
                            'Options',{'Save','Cancel'}, ...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Save'
                                SAVEButtonPushed(app);
                                return
                            case 'Cancel'
                                return
                        end
                    end
                end

                % Check passed! Update
                % Reset plan_selected
                app.plan_selected=nan;
                app.Run1Button.Value=0;
                app.Run2Button.Value=0;
                app.Run3Button.Value=0;
                app.Run4Button.Value=0;
                app.Run5Button.Value=0;

                % Clean DialogApp
                CleanAllTable(app.DialogApp)

                app.pick_index_now=app.pick_index_next;
                d = uiprogressdlg(app.UIFigure,'Title','Updating...',...
                    'Message',strcat('Loading:',app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},'...'),'Indeterminate','on','Cancelable','on');
                app.is_saved=false;
                app.is_marked=false;
                ReNewFigures(app);
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Generate one Records
                app.pick_table_now=HkcPick_GetOneTableLine(app.pick_index_now,app.inputdir_index,app.Hkc_alltextdata,app.Hkc_allstatistics);
                %assignin("base","pick_table",app.pick_table_now)
                UpdateStabilityTable(app.DialogApp)
                close(d)
            else

                if(~app.is_saved)
                    msg = strcat('Pick for :',num2str(app.pick_index_now),'_',...
                        app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now} ,' is not saved. Save now?');
                    title = 'Confirm Save';
                    selection = uiconfirm(app.UIFigure,msg,title, ...
                        'Options',{'Save','Cancel'}, ...
                        'DefaultOption',1,'CancelOption',2);
                    switch selection
                        case 'Save'
                            SAVEButtonPushed(app);
                            return
                        case 'Cancel'
                            return
                    end
                end

                msg = strcat('Pick for :',num2str(app.pick_index_next),'_',...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_next} ,' already exists. Do you want change it?');
                title = 'Confirm Change';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Change','Do Not Change','Cancel Loading'}, ...
                    'DefaultOption',2,'CancelOption',2);
                switch selection
                    case 'Change'
                        app.is_saved=false;
                        app.is_marked=false;
                    case 'Do Not Change'
                        app.is_saved=true;
                        app.is_marked=true;
                        app.SAVEButton.Enable='off';
                        app.Run1Button.Enable='off';
                        app.Run2Button.Enable='off';
                        app.Run3Button.Enable='off';
                        app.Run4Button.Enable='off';
                        app.Run5Button.Enable='off';

                        app.DialogApp.Run1Button.Enable='off';
                        app.DialogApp.Run2Button.Enable='off';
                        app.DialogApp.Run3Button.Enable='off';
                        app.DialogApp.Run4Button.Enable='off';
                        app.DialogApp.Run5Button.Enable='off';
                    case 'Cancel Loading'
                        return
                    otherwise
                        return
                end

                app.pick_index_now=app.pick_index_next;
                d = uiprogressdlg(app.UIFigure,'Title','Updating...',...
                    'Message',strcat('Loading:',app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},'...'),'Indeterminate','on','Cancelable','on');
                nn=find(app.HkcPick_tableall.sta_idx==app.pick_index_now, 1);
                mm=app.HkcPick_tableall.run_idx(nn);
                ReNewFigures(app);
                switch mm
                    case 1
                        app.Run1Button.Value=1;
                        app.Run2Button.Value=0;
                        app.Run3Button.Value=0;
                        app.Run4Button.Value=0;
                        app.Run5Button.Value=0;

                        app.DialogApp.Run1Button.Value=1;
                        app.DialogApp.Run2Button.Value=0;
                        app.DialogApp.Run3Button.Value=0;
                        app.DialogApp.Run4Button.Value=0;
                        app.DialogApp.Run5Button.Value=0;

                        app.plan_selected=1;
                    case 2
                        app.Run2Button.Value=1;
                        app.Run1Button.Value=0;
                        app.Run3Button.Value=0;
                        app.Run4Button.Value=0;
                        app.Run5Button.Value=0;

                        app.DialogApp.Run1Button.Value=0;
                        app.DialogApp.Run2Button.Value=1;
                        app.DialogApp.Run3Button.Value=0;
                        app.DialogApp.Run4Button.Value=0;
                        app.DialogApp.Run5Button.Value=0;

                        app.plan_selected=2;
                    case 3
                        app.Run3Button.Value=1;
                        app.Run1Button.Value=0;
                        app.Run2Button.Value=0;
                        app.Run4Button.Value=0;
                        app.Run5Button.Value=0;

                        app.DialogApp.Run1Button.Value=0;
                        app.DialogApp.Run2Button.Value=0;
                        app.DialogApp.Run3Button.Value=1;
                        app.DialogApp.Run4Button.Value=0;
                        app.DialogApp.Run5Button.Value=0;

                        app.plan_selected=3;
                    case 4
                        app.Run4Button.Value=1;
                        app.Run1Button.Value=0;
                        app.Run2Button.Value=0;
                        app.Run3Button.Value=0;
                        app.Run5Button.Value=0;

                        app.DialogApp.Run1Button.Value=0;
                        app.DialogApp.Run2Button.Value=0;
                        app.DialogApp.Run3Button.Value=0;
                        app.DialogApp.Run4Button.Value=1;
                        app.DialogApp.Run5Button.Value=0;

                        app.plan_selected=4;
                    case 5
                        app.Run5Button.Value=1;
                        app.Run1Button.Value=0;
                        app.Run2Button.Value=0;
                        app.Run3Button.Value=0;
                        app.Run4Button.Value=0;

                        app.DialogApp.Run1Button.Value=0;
                        app.DialogApp.Run2Button.Value=0;
                        app.DialogApp.Run3Button.Value=0;
                        app.DialogApp.Run4Button.Value=0;
                        app.DialogApp.Run5Button.Value=1;

                        app.plan_selected=5;
                    otherwise
                        errordlg("Error in reading run_idx!",'Error Message','error')
                end
                close(d)
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                app.pick_table_now=app.HkcPick_tableall(nn,:);
                UpdateStabilityTable(app.DialogApp)
                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end

        end

        % Value changed function: Run1Button
        function Run1ButtonValueChanged(app, event)
            value = app.Run1Button.Value;
            if(value)
                app.Run2Button.Value=0;
                app.Run3Button.Value=0;
                app.Run4Button.Value=0;
                app.Run5Button.Value=0;
                app.plan_selected=1;
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Update RecordTable
                app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                assignin("base","pick_table",app.pick_table_now)
                app.is_saved=false;

                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end
        end

        % Value changed function: Run2Button
        function Run2ButtonValueChanged(app, event)
            value = app.Run2Button.Value;
            if(value)
                app.Run1Button.Value=0;
                app.Run3Button.Value=0;
                app.Run4Button.Value=0;
                app.Run5Button.Value=0;
                app.plan_selected=2;
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Update RecordTable
                app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                assignin("base","pick_table",app.pick_table_now)
                app.is_saved=false;

                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end
        end

        % Value changed function: Run3Button
        function Run3ButtonValueChanged(app, event)
            value = app.Run3Button.Value;
            if(value)
                app.Run1Button.Value=0;
                app.Run2Button.Value=0;
                app.Run4Button.Value=0;
                app.Run5Button.Value=0;
                app.plan_selected=3;
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Update RecordTable
                app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                assignin("base","pick_table",app.pick_table_now)
                app.is_saved=false;

                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end
        end

        % Value changed function: Run4Button
        function Run4ButtonValueChanged(app, event)
            value = app.Run4Button.Value;
            if(value)
                app.Run1Button.Value=0;
                app.Run2Button.Value=0;
                app.Run3Button.Value=0;
                app.Run5Button.Value=0;
                app.plan_selected=4;
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Update RecordTable
                app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                assignin("base","pick_table",app.pick_table_now)
                app.is_saved=false;

                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end
        end

        % Value changed function: Run5Button
        function Run5ButtonValueChanged(app, event)
            value = app.Run5Button.Value;
            if(value)
                app.Run1Button.Value=0;
                app.Run2Button.Value=0;
                app.Run3Button.Value=0;
                app.Run4Button.Value=0;
                app.plan_selected=5;
                app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now},' ... Selecting Run',num2str(app.plan_selected));
                % Update RecordTable
                app.pick_table_now=HkcPick_UseDeterminedPlantoUpdate(app.plan_selected,app.pick_table_now,app.Hkc_alltextdata);
                assignin("base","pick_table",app.pick_table_now)
                app.is_saved=false;

                UpdateResultTable(app.DialogApp)
                UpdateAprioriTable(app.DialogApp)
            end
        end

        % Button pushed function: EnlargeFigSizeButton
        function EnlargeFigSizeButtonPushed(app, event)
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
            EnlargeFigs(app);
        end

        % Button pushed function: ResetFigSizeButton
        function ResetFigSizeButtonPushed(app, event)
            % Reduce App window size to trigger auto adjustion
            ss = get(0,'ScreenSize');
            ss(1)=100;
            ss(2)=100;
            ss(3)=round(ss(3)/2);
            ss(4)=round(ss(4)/2);
            app.UIFigure.Position=ss;
            pause(0.7)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % check whether marked
            if(~app.is_marked)
                msg = strcat('Pick for :',num2str(app.pick_index_now),'_',...
                    app.Hkc_alltextdata.run3.inputHkc.stnm{app.pick_index_now} ,' is not marked. Mark now?');
                title = 'Confirm Mark';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Mark','Cancel'}, ...
                    'DefaultOption',1,'CancelOption',2);
                switch selection
                    case 'Mark'
                        GetNowTableFromDialogApp(app);
                        return
                    case 'Cancel'
                        return
                end
            end

            [n,~]=size(app.HkcPick_tableall);
            idx4merge=nan;
            if(n~=0)
                for m=1:n
                    if(app.pick_index_now==app.HkcPick_tableall.sta_idx(m))
                        idx4merge=m;
                    end
                end
            end

            if(isnan(idx4merge))
                % merge to new line
                app.HkcPick_tableall=[app.HkcPick_tableall;app.pick_table_now];
            else
                % merge to existed line
                % delete old line
                app.HkcPick_tableall(idx4merge,:)=app.pick_table_now;
            end
            % sort table
            [app.HkcPick_tableall,~] = sortrows(app.HkcPick_tableall,'sta_idx');
            app.is_saved=true;
        end

        % Menu selected function: OpenPickAnalysisToolMenu
        function OpenPickAnalysisToolMenuSelected(app, event)
            runPickAnalysisTool(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');

            if(~app.is_savedall || ~app.is_genfile)
                msg = ["Plese Save All Results and Generate Plotting Data";...
                    strcat("Results Saved :",num2str(app.is_savedall));...
                    strcat('Data Generated :',num2str(app.is_genfile))];
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...SAVEButtonPushed(app);
                    'Options',{'Forced Exit','Save Results','Generate Plotting Data','Cancel'}, ...
                    'DefaultOption',4,'CancelOption',3);
                switch selection
                    case 'Forced Exit'
                        delete(app.DialogApp)
                        delete(app)
                    case 'Save Results'
                        SaveHkcPickResultsMenuSelected(app);
                        if(app.is_genfile)
                            fprintf(fileID,"%d %s\n",size(app.HkcPick_tableall,1),'records in total are saved!');
                            fprintf(fileID,"%s %s\n",'[End Run] HkcPick_2ContinuePick at: ',datestr(now,0));
                            fclose(fileID);
                            % Delete both apps
                            delete(app.DialogApp)
                            delete(app)
                            return
                        else
                            fclose(fileID);
                            return
                        end
                    case 'Generate Plotting Data'
                        GeneratePlottingDataMenuSelected(app);
                        if(app.is_savedall)
                            fprintf(fileID,"%d %s\n",size(app.HkcPick_tableall,1),'records in total are saved!');
                            fprintf(fileID,"%s %s\n",'[End Run] HkcPick_2ContinuePick at: ',datestr(now,0));
                            fclose(fileID);
                            % Delete both apps
                            delete(app.DialogApp)
                            delete(app)
                            return
                        else
                            fclose(fileID);
                            return
                        end
                    case 'Cancel'
                        fclose(fileID);
                        return
                end
            else
                % Delete both apps
                fprintf(fileID,"%d %s\n",size(app.HkcPick_tableall,1),'records in total are saved!');
                fprintf(fileID,"%s %s\n",'[End Run] HkcPick_2ContinuePick at: ',datestr(now,0));
                fclose(fileID);
                delete(app.DialogApp)
                delete(app)
            end

        end

        % Menu selected function: SaveHkcPickResultsMenu
        function SaveHkcPickResultsMenuSelected(app, event)
            app.is_savedall=true;
            cd(app.Hkc_pick_app_dir)
            writetable(app.HkcPick_tableall,'AllHkcPickData.txt')
            filebak=strcat('AllHkcPickData',datestr(now,'yymmdd_HHMMss'),'.txt');
            copyfile('AllHkcPickData.txt',filebak)
            movefile(filebak,'DataBackUp')
        end

        % Menu selected function: GeneratePlottingDataMenu
        function GeneratePlottingDataMenuSelected(app, event)
            cd(app.Hkc_pick_dir)
            % Get Hk before
            idx=~isnan(app.HkcPick_tableall.Hbefore);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','Hbefore','kappabefore','demo'}),strcat(app.projectname,'_Hkbefore.txt'))
            % Get Hk after
            idx=~isnan(app.HkcPick_tableall.Hafter);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','Hafter','kappaafter','demo'}),strcat(app.projectname,'_Hkafter.txt'))
            % Get theta2
            writetable(app.HkcPick_tableall(:,{'stnm','lon','lat','PsA2','PsTheta2','M1A2','M1Theta2'}),strcat(app.projectname,'_Theta2.txt'))
            % Get theta2Qc
            writetable(app.HkcPick_tableall(:,{'stnm','lon','lat',...
                'PsA2_stdraw','PsTheta2_stdraw','PsTheta2_rhostdraw','PsTheta2_sigmastdraw',...
                'PsA2_stdnorm','PsTheta2_stdnorm','PsTheta2_rhostdnorm','PsTheta2_sigmastdnorm',...
                'M1A2_stdraw','M1Theta2_stdraw','M1Theta2_rhostdraw','M1Theta2_sigmastdraw',...
                'M1A1_stdnorm','M1Theta1_stdnorm','M1Theta1_rhostdnorm','M1Theta1_sigmastdnorm'}),...
                strcat(app.projectname,'_Theta2QualityControl.txt'))
            % Get BAZ deficiency
            writetable(app.HkcPick_tableall(:,{'stnm','lon','lat','BAZ_coverage'}),strcat(app.projectname,'_BAZ_deficiency.txt'))
            % get good demostration stations
            idx=(app.HkcPick_tableall.demo==1);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','demo','Hbefore','kappabefore','Hafter','kappaafter'}),...
                strcat(app.projectname,'_GoodDemo.txt'))
            % get bad demostration stations
            idx=(app.HkcPick_tableall.demo==-1);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','demo','Hbefore','kappabefore','Hafter','kappaafter','isnote','note'}),...
                strcat(app.projectname,'_BadDemo.txt'))
            % get rerun stations
            idx=(app.HkcPick_tableall.rerun==1);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','rerun','Hbefore','kappabefore','Hafter','kappaafter','isnote','note'}),...
                strcat(app.projectname,'_rerun.txt'))
            % get Notes
            idx=(app.HkcPick_tableall.isnote==1);
            writetable(app.HkcPick_tableall(idx,{'stnm','lon','lat','rerun','demo','isnote','note'}),...
                strcat(app.projectname,'_note.txt'))

            app.is_genfile=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Tooltip = {'Click here for important operations'};
            app.Menu.Text = '    Menu   ';
            app.SaveHkcPickResultsMenu = uimenu(app.Menu);
            app.SaveHkcPickResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveHkcPickResultsMenuSelected, true);
            app.SaveHkcPickResultsMenu.Tooltip = {'Save all Hkc-Pick information.'};
            app.SaveHkcPickResultsMenu.Accelerator = 'S';
            app.SaveHkcPickResultsMenu.Text = 'Save Hkc-Pick Results';
            app.GeneratePlottingDataMenu = uimenu(app.Menu);
            app.GeneratePlottingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratePlottingDataMenuSelected, true);
            app.GeneratePlottingDataMenu.Tooltip = {'Reorganize Hkc-Pick results as files that are suitable for plotting.'};
            app.GeneratePlottingDataMenu.Accelerator = 'G';
            app.GeneratePlottingDataMenu.Text = 'Generate Plotting Data';
            app.OpenPickAnalysisToolMenu = uimenu(app.UIFigure);
            app.OpenPickAnalysisToolMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPickAnalysisToolMenuSelected, true);
            app.OpenPickAnalysisToolMenu.Text = '   Open Pick Analysis Tool   ';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.M1_2 = uiaxes(app.GridLayout);
            app.M1_2.Visible = 'off';
            app.M2_2 = uiaxes(app.GridLayout);
            app.M2_2.Visible = 'off';
            app.M2_3 = uiaxes(app.GridLayout);
            app.M2_3.Visible = 'off';
            app.Ps_4 = uiaxes(app.GridLayout);
            app.Ps_4.Visible = 'off';
            app.M1_4 = uiaxes(app.GridLayout);
            app.M1_4.Visible = 'off';
            app.M2_4 = uiaxes(app.GridLayout);
            app.M2_4.Visible = 'off';
            app.Ps_5 = uiaxes(app.GridLayout);
            app.Ps_5.Visible = 'off';
            app.M1_5 = uiaxes(app.GridLayout);
            app.M1_5.Visible = 'off';
            app.M2_5 = uiaxes(app.GridLayout);
            app.M2_5.Visible = 'off';
            app.allbefore = uiaxes(app.GridLayout);
            app.allbefore.Visible = 'off';
            app.hkbefore = uiaxes(app.GridLayout);
            app.hkbefore.Visible = 'off';
            app.Ps_1 = uiaxes(app.GridLayout);
            app.Ps_1.Visible = 'off';
            app.M1_1 = uiaxes(app.GridLayout);
            app.M1_1.Visible = 'off';
            app.M2_1 = uiaxes(app.GridLayout);
            app.M2_1.Visible = 'off';
            app.Ps_2 = uiaxes(app.GridLayout);
            app.Ps_2.Visible = 'off';
            app.Ps_3 = uiaxes(app.GridLayout);
            app.Ps_3.Visible = 'off';
            app.M1_3 = uiaxes(app.GridLayout);
            app.M1_3.Visible = 'off';
            app.Hk_1 = uiaxes(app.GridLayout);
            app.Hk_1.Visible = 'off';
            app.Hk_2 = uiaxes(app.GridLayout);
            app.Hk_2.Visible = 'off';
            app.Hk_3 = uiaxes(app.GridLayout);
            app.Hk_3.Visible = 'off';
            app.Hk_4 = uiaxes(app.GridLayout);
            app.Hk_4.Visible = 'off';
            app.Hk_5 = uiaxes(app.GridLayout);
            app.Hk_5.Visible = 'off';
            app.GotoButton = uibutton(app.GridLayout, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Go to';
            app.TotalNumLabel = uilabel(app.GridLayout);
            app.TotalNumLabel.Text = 'TotalNum';
            app.ProjectInformationLabel = uilabel(app.GridLayout);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.StnumSpinner = uispinner(app.GridLayout);
            app.Run1Button = uibutton(app.GridLayout, 'state');
            app.Run1Button.ValueChangedFcn = createCallbackFcn(app, @Run1ButtonValueChanged, true);
            app.Run1Button.Text = 'Run1';
            app.Run3Button = uibutton(app.GridLayout, 'state');
            app.Run3Button.ValueChangedFcn = createCallbackFcn(app, @Run3ButtonValueChanged, true);
            app.Run3Button.Text = 'Run3';
            app.Run4Button = uibutton(app.GridLayout, 'state');
            app.Run4Button.ValueChangedFcn = createCallbackFcn(app, @Run4ButtonValueChanged, true);
            app.Run4Button.Text = 'Run4';
            app.Run5Button = uibutton(app.GridLayout, 'state');
            app.Run5Button.ValueChangedFcn = createCallbackFcn(app, @Run5ButtonValueChanged, true);
            app.Run5Button.Text = 'Run5';
            app.Run2Button = uibutton(app.GridLayout, 'state');
            app.Run2Button.ValueChangedFcn = createCallbackFcn(app, @Run2ButtonValueChanged, true);
            app.Run2Button.Text = 'Run2';
            app.EnlargeFigSizeButton = uibutton(app.GridLayout, 'push');
            app.EnlargeFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFigSizeButtonPushed, true);
            app.EnlargeFigSizeButton.Tooltip = {'Enlarge the picture proportionally.'};
            app.EnlargeFigSizeButton.Text = 'Enlarge FigSize';
            app.ResetFigSizeButton = uibutton(app.GridLayout, 'push');
            app.ResetFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFigSizeButtonPushed, true);
            app.ResetFigSizeButton.Tooltip = {'When the image is enlarged too much, restore it to its original size.'};
            app.ResetFigSizeButton.Text = 'Reset FigSize';
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_2ContinuePick(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end