classdef HkcPick_4QuickReview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpenReviewHelperMenu     matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        SummaryLabel             matlab.ui.control.Label
        ResetFigSizeButton       matlab.ui.control.Button
        EnlargeFigSizeButton     matlab.ui.control.Button
        Run2Button               matlab.ui.control.StateButton
        Run5Button               matlab.ui.control.StateButton
        Run4Button               matlab.ui.control.StateButton
        Run3Button               matlab.ui.control.StateButton
        Run1Button               matlab.ui.control.StateButton
        StnumSpinner             matlab.ui.control.Spinner
        ProjectInformationLabel  matlab.ui.control.Label
        TotalNumLabel            matlab.ui.control.Label
        M1_1                     matlab.ui.control.UIAxes
        M2_1                     matlab.ui.control.UIAxes
        Ps_2                     matlab.ui.control.UIAxes
        Ps_3                     matlab.ui.control.UIAxes
        M1_3                     matlab.ui.control.UIAxes
        Hk_1                     matlab.ui.control.UIAxes
        Hk_2                     matlab.ui.control.UIAxes
        Hk_3                     matlab.ui.control.UIAxes
        Hk_4                     matlab.ui.control.UIAxes
        Hk_5                     matlab.ui.control.UIAxes
        M2_5                     matlab.ui.control.UIAxes
        Ps_1                     matlab.ui.control.UIAxes
        hkbefore                 matlab.ui.control.UIAxes
        allbefore                matlab.ui.control.UIAxes
        M1_5                     matlab.ui.control.UIAxes
        Ps_5                     matlab.ui.control.UIAxes
        M2_4                     matlab.ui.control.UIAxes
        M1_4                     matlab.ui.control.UIAxes
        Ps_4                     matlab.ui.control.UIAxes
        M2_3                     matlab.ui.control.UIAxes
        M2_2                     matlab.ui.control.UIAxes
        M1_2                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Hkc Pick program Info
        Hkc_pick_dir      % Location for Hkc_Pick results
        Hkc_pick_app_dir  % Location for Hkc_Pick raw data
        projectname       % Name of this Hkc-Pick job
        Hkc_data_dir      % New Hkc Results with no picking
        figsuffix='.png'
        run1_dir          % Location for run1
        run2_dir          % Location for run2
        run3_dir          % Location for run3
        run4_dir          % Location for run4
        run5_dir          % Location for run5

        % Pick Control
        inputdir_index      % index of input directory
        pick_index_now = 0  % index of station to pick now

        pick_index_next     % index of station to pick next

        station_idx_new

        UP=0.01           % Cutting HA figures
        DW=0.435          % UP,DW,LF,RF >0
        LF=0.03           % UP,DW,LF,RF <=1
        RT=0.8

        UPhk=0.07         % Cutting hk figures
        DWhk=0.35
        LFhk=0.01
        RThk=0.4

        UPbefore=0.07     % Cutting allbefore figures
        DWbefore=0.98
        LFbefore=0.001
        RTbefore=0.5

        % Hkc Pick Data
        Hkc_alldatadirs   % Data Paths
        Hkc_alltextdata   % Output of HkcPick_AppReadData
        plan_selected     % Selected plan
        HkcPick_tableall  % a table that saves all data

        % Interactive HkcPick Analysis Dialog
        HelperApp

    end

    properties (Access = public)
        pick_table_now      % table of station to pick now
    end


    methods (Access = private)

        function  HkcPickShowFig(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UP*a):floor(app.DW*a),ceil(app.LF*b):floor(app.RT*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFighk(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPhk*a):floor(app.DWhk*a),ceil(app.LFhk*b):floor(app.RThk*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  HkcPickShowFigallbefore(app,figregion,fileloc)
            figregion.Visible='on';
            im=imread(fileloc);
            [a,b,~]=size(im);
            imshow(im(ceil(app.UPbefore*a):floor(app.DWbefore*a),ceil(app.LFbefore*b):floor(app.RTbefore*b),:),'Parent',figregion)
            % no panInteraction and zoomInteraction
            figregion.Interactions=[];
        end

        function  ReNewFigures(app,fig_idx_now)
            %% Run1
            % Ps
            filename=fullfile(app.Hkc_data_dir,app.run1_dir,'run1_0_HA1',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_1, filename);
            % M1
            filename=fullfile(app.Hkc_data_dir,app.run1_dir,'run1_0_HA2',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_1, filename);
            % M2
            filename=fullfile(app.Hkc_data_dir,app.run1_dir,'run1_0_HA3',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_1, filename);
            % hk_after
            filename=fullfile(app.Hkc_data_dir,app.run1_dir,'run1_hk_after',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_1, filename);

            %%Run2
            % Ps
            filename=fullfile(app.Hkc_data_dir,app.run2_dir,'run2_0_HA1',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_2, filename);
            % M1
            filename=fullfile(app.Hkc_data_dir,app.run2_dir,'run2_0_HA2',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_2, filename);
            % M2
            filename=fullfile(app.Hkc_data_dir,app.run2_dir,'run2_0_HA3',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_2, filename);
            % hk_after
            filename=fullfile(app.Hkc_data_dir,app.run2_dir,'run2_hk_after',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_2, filename);

            %%Run3
            % Ps
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_0_HA1',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_3, filename);
            % M1
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_0_HA2',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_3, filename);
            % M2
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_0_HA3',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_3, filename);
            % hk_after
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_hk_after',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_3, filename);

            %%Run4
            % Ps
            filename=fullfile(app.Hkc_data_dir,app.run4_dir,'run4_0_HA1',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_4, filename);
            % M1
            filename=fullfile(app.Hkc_data_dir,app.run4_dir,'run4_0_HA2',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_4, filename);
            % M2
            filename=fullfile(app.Hkc_data_dir,app.run4_dir,'run4_0_HA3',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_4, filename);
            % hk_after
            filename=fullfile(app.Hkc_data_dir,app.run4_dir,'run4_hk_after',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_4, filename);

            %%Run5
            % Ps
            filename=fullfile(app.Hkc_data_dir,app.run5_dir,'run5_0_HA1',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.Ps_5, filename);
            % M1
            filename=fullfile(app.Hkc_data_dir,app.run5_dir,'run5_0_HA2',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M1_5, filename);
            % M2
            filename=fullfile(app.Hkc_data_dir,app.run5_dir,'run5_0_HA3',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFig(app, app.M2_5, filename);
            % hk_after
            filename=fullfile(app.Hkc_data_dir,app.run5_dir,'run5_hk_after',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.Hk_5, filename);

            %%Allbefore
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_allbefore',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFigallbefore(app, app.allbefore, filename);

            %%hkbefore
            filename=fullfile(app.Hkc_data_dir,app.run3_dir,'run3_hk_before',strcat(num2str(fig_idx_now),app.figsuffix));
            HkcPickShowFighk(app, app.hkbefore, filename);
        end

        function EnlargeHA(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargehk(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.093;
            figposi(4)=figposi(4)*1.093;
            figregion.InnerPosition=figposi;
        end

        function Enlargebefore(app,figregion)
            % Enlarge the image
            figregion.PositionConstraint='innerposition';
            figposi=figregion.OuterPosition;
            %figposi(2)=figposi(2)-figposi(4)*0.1;
            figposi(3)=figposi(3)*1.03;
            figposi(4)=figposi(4)*1.4;
            figregion.InnerPosition=figposi;
        end

        function EnlargeFigs(app)
            EnlargeHA(app, app.Ps_1);EnlargeHA(app, app.M1_1);EnlargeHA(app, app.M2_1);
            EnlargeHA(app, app.Ps_2);EnlargeHA(app, app.M1_2);EnlargeHA(app, app.M2_2);
            EnlargeHA(app, app.Ps_3);EnlargeHA(app, app.M1_3);EnlargeHA(app, app.M2_3);
            EnlargeHA(app, app.Ps_4);EnlargeHA(app, app.M1_4);EnlargeHA(app, app.M2_4);
            EnlargeHA(app, app.Ps_5);EnlargeHA(app, app.M1_5);EnlargeHA(app, app.M2_5);

            Enlargehk(app,app.Hk_1);Enlargehk(app,app.Hk_2);Enlargehk(app,app.Hk_3);
            Enlargehk(app,app.Hk_4);Enlargehk(app,app.Hk_5);

            Enlargebefore(app, app.allbefore);
            Enlargehk(app,app.hkbefore);
        end


        function runReviewHelperTool(app)
            % Disable Plot Options button while dialog is open
            app.OpenReviewHelperMenu.Enable = 'off';

            % Open the options dialog and pass inputs
            app.HelperApp = HkcPick_QuickReviewHelper(app, app.HkcPick_tableall);
        end

        function ReadOneStation(app,datadir)
            % Goto Data Directory and start to read data
            cd(datadir)
            app.run1_dir=string(ls('*Run1'));
            app.run2_dir=string(ls('*Run2'));
            app.run3_dir=string(ls('*Run3'));
            app.run4_dir=string(ls('*Run4'));
            app.run5_dir=string(ls('*Run5'));
        end
    end

    methods (Access = public)


        function UpdateFigures(app)
            app.pick_index_next=app.StnumSpinner.Value;

            if(app.pick_index_next==app.pick_index_now)
                % no change and do nothing
                return
            end


            % Check passed! Update to a Different pick
            app.pick_index_now=app.pick_index_next;
            % Reset plan_selected
            app.plan_selected=app.HkcPick_tableall.run_idx(app.pick_index_now);
            app.Run1Button.Value=0;
            app.Run2Button.Value=0;
            app.Run3Button.Value=0;
            app.Run4Button.Value=0;
            app.Run5Button.Value=0;

            app.Run1Button.Enable='off';
            app.Run2Button.Enable='off';
            app.Run3Button.Enable='off';
            app.Run4Button.Enable='off';
            app.Run5Button.Enable='off';


            switch app.plan_selected
                case 1
                    app.Run1Button.Value=1;
                    app.Run1Button.Enable='on';
                case 2
                    app.Run2Button.Value=1;
                    app.Run2Button.Enable='on';
                case 3
                    app.Run3Button.Value=1;
                    app.Run3Button.Enable='on';
                case 4
                    app.Run4Button.Value=1;
                    app.Run4Button.Enable='on';
                case 5
                    app.Run5Button.Value=1;
                    app.Run5Button.Enable='on';
                otherwise
                    errordlg("Error in setting plan_selected!",'Error Message','error')
                    app.HelperApp.is_fail=true;
                    return
            end

            % Find Correct DataDir
            datadir_idx_now=app.HkcPick_tableall.datasource(app.pick_index_now);
            try
                datadir_path_now=app.Hkc_alldatadirs.Path(app.Hkc_alldatadirs.PathIdx==datadir_idx_now);
                ReadOneStation(app,datadir_path_now)
                app.Hkc_data_dir=datadir_path_now;
                % Read Station List
                cd(app.Hkc_data_dir);
                description_file=string(ls('*.txt'));
                opts=detectImportOptions(description_file,'ReadVariableNames',false,'Delimiter',{' '},'ConsecutiveDelimitersRule','join');
                opts.DataLines=[1,Inf];
                clear temp
                temp=readtable(description_file,opts);
                station_list=string(temp.Var1);
            catch ME
                errordlg(["Error in Entering DataDir and Reading Station List! Error Message:";string(ME.message)],'Error Message','error')
                app.HelperApp.is_fail=true;
                return
            end

            % Update Figures
            d = uiprogressdlg(app.UIFigure,'Title','Updating...',...
                'Message',strcat("Loading:",string(app.HkcPick_tableall.stnm(app.pick_index_now))," for viewing..."),'Indeterminate','on','Cancelable','on');
            % Find Correct figure idx
            % Renew Figures
            % find correct station_idx_new
            app.station_idx_new=find((station_list==string(app.HkcPick_tableall.stnm(app.pick_index_now)))==1);
            try
                ReNewFigures(app,app.station_idx_new);
            catch ME
                errordlg(["Error in Renewing figures! Error Message:";string(ME.message)],'Error Message','error')
                app.HelperApp.is_fail=true;
                return
            end

            app.ProjectInformationLabel.Text=strcat(app.projectname,': ',num2str(app.pick_index_now),...
                string(app.HkcPick_tableall.stnm(app.pick_index_now)),' ... Selecting Run',num2str(app.plan_selected));

            % Update to one new Records
            app.pick_table_now=app.HkcPick_tableall(app.pick_index_now,:);
            %assignin("base","pick_table",app.pick_table_now)

            % Update Summary Label
            textsummary_now=["Results (after << before ):";
                "    H: "+string(sprintf('%.2f km',app.pick_table_now.Hafter))+" << "+string(sprintf('%.2f km',app.pick_table_now.Hbefore));
                "    k: "+string(sprintf('%.2f',app.pick_table_now.kappaafter))+" << "+string(sprintf('%.2f',app.pick_table_now.kappabefore));
                "Quality Diagnosis:";
                "    Demo: "+string(sprintf('%d',app.pick_table_now.demo));
                "    A priori:";
                "        dPs: "+string(sprintf('%.2f s',app.pick_table_now.dtPs_hkc));
                "        dM1: "+string(sprintf('%.2f s',app.pick_table_now.dtM1_hkc));
                "        dM2: "+string(sprintf('%.2f s',app.pick_table_now.dtM2_hkc));
                "    BAZ_Deficiency: "+string(sprintf('%.2f',app.pick_table_now.BAZ_coverage));
                "Time Results (HA || HK)";
                "    Ps: "+string(sprintf('%.2f s',app.pick_table_now.tPs))+" || "+string(sprintf('%.2f s',app.pick_table_now.tPs_hk));
                "    M1: "+string(sprintf('%.2f s',app.pick_table_now.tM1))+" || "+string(sprintf('%.2f s',app.pick_table_now.tM1_hk));
                "    M2: "+string(sprintf('%.2f s',app.pick_table_now.tM2))+" || "+string(sprintf('%.2f s',app.pick_table_now.tM2_hk))];
            app.SummaryLabel.Text=textsummary_now;
            app.SummaryLabel.Visible='on';
            close(d)

        end

        function SaveOneFigure(app,image_saving_dir,image_type)
            stnm_now=app.pick_table_now.stnm;
            exportapp(app.UIFigure,fullfile(image_saving_dir,strcat(string(stnm_now),image_type)));
        end


        function MergeOneStation(app,image_saving_dir)
            % Determine which plan is selected
            switch app.plan_selected
                case 1
                    plan_path=app.run1_dir;
                case 2
                    plan_path=app.run2_dir;
                case 3
                    plan_path=app.run3_dir;
                case 4
                    plan_path=app.run4_dir;
                case 5
                    plan_path=app.run5_dir;
                otherwise
                    errordlg("Error in setting plan_selected!",'Error Message','error')
                    return
            end
            % Make new dir to store figures
            new_dir=fullfile(image_saving_dir,string(app.HkcPick_tableall.stnm(app.pick_index_now)));
            mkdir(new_dir);
            old_dir=fullfile(app.Hkc_data_dir,plan_path);
            try
                %Ps
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_0_HA1'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_Ps',app.figsuffix));
                %M1
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_0_HA2'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_M1',app.figsuffix));
                %M2
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_0_HA3'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_M2',app.figsuffix));
                %allafter
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_allafter'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_allafter',app.figsuffix));
                %allbefore
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_allbefore'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_allbefore',app.figsuffix));
                %hk_after
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_hk_after'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_hk_after',app.figsuffix));
                %hk_before
                filename=fullfile(old_dir,strcat('run',num2str(app.plan_selected),'_hk_before'),strcat(num2str(app.station_idx_new),app.figsuffix));
                copyfile(filename,strcat(new_dir,filesep,'run',num2str(app.plan_selected),'_hk_before',app.figsuffix));
                %create datasource records
                fileID=fopen(fullfile(new_dir,'FigureSource.txt'),'w');
                fprintf(fileID,strcat(replace(old_dir,'\','\\'),'\n'));
                fclose(fileID);
            catch ME
                errordlg(["Error in Merging figures! Error Message:";ME.message],'Error Message','error')
                app.HelperApp.is_fail=true;
                return
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dirpick)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;

            %% Get Old Picks
            app.Hkc_pick_dir=string(dirpick);

            temp=split(app.Hkc_pick_dir,filesep);
            app.projectname=string(temp(end));
            app.ProjectInformationLabel.Text=app.projectname;


            try
                cd(app.Hkc_pick_dir)
                cd HkcPickAppData
                app.Hkc_pick_app_dir=pwd;
            catch
                errordlg("Error in Entering HkcPickAppData! Please Check whether it exists!",'Error Message','error')
                return
            end

            % Write Start Records
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');
            fprintf(fileID,"%s %s\n",'[Start Run] HkcPick_4QuickReview at: ',datestr(now,0));
            fclose(fileID);
            % Read Data Paths
            try
                lines=readlines(fullfile(app.Hkc_pick_app_dir,"InputDir.txt"),"EmptyLineRule","skip");
                [l,~]=size(lines);
                if(l==0)
                    errordlg("Empty Data Directory Record!",'Error Message','error')
                    return
                else
                    % Read Data Path
                    clear temp
                    if(l>1)
                        temp=array2table(split(strtrim(lines)),'VariableNames',{'PathIdx','Path'});
                        app.Hkc_alldatadirs=convertvars(temp,'PathIdx','double');
                    elseif(l==1)
                        if(size(split(strtrim(lines)),1)>1 && size(split(strtrim(lines)),2)==1)
                            temp=array2table(transpose(split(strtrim(lines))),'VariableNames',{'PathIdx','Path'});
                            app.Hkc_alldatadirs=convertvars(temp,'PathIdx','double');
                        elseif(size(split(strtrim(lines)),1)==1 && size(split(strtrim(lines)),2)>1)
                            temp=array2table(split(strtrim(lines)),'VariableNames',{'PathIdx','Path'});
                            app.Hkc_alldatadirs=convertvars(temp,'PathIdx','double');
                        else
                            errordlg("Error in reading one-line file InputDir.txt! Please check.",'Error Message','error')
                            return
                        end
                    end
                end
            catch
                errordlg("Error in reading InputDir.txt file!",'Error Message','error')
                return
            end

            % Read STDall.txt (may)
            d = uiprogressdlg(app.UIFigure,'Title','Initalizing, please wait...',...
                'Message','Calculating normalization constant...','Cancelable','on');

            EnlargeFigs(app);
            d.Value=0.33;

            % Read Records
            if(exist(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'),"file")==0)
                errordlg("Could not found AllHkcPickData.txt!",'Error Message','error')
                return
            else
                %Read Existed Data
                opts = detectImportOptions(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'));
                opts = setvartype(opts,{'stnm','note'},'string');
                app.HkcPick_tableall=readtable(fullfile(app.Hkc_pick_app_dir,'AllHkcPickData.txt'),opts);

                [l,~]=size(app.HkcPick_tableall);

                app.TotalNumLabel.Text=strcat('/',num2str(l));
                if(l>1)
                    app.StnumSpinner.Limits=[1 l];
                    app.StnumSpinner.Value=1;
                elseif(l==1)
                    app.StnumSpinner.Limits=[0 1];
                    app.StnumSpinner.Value=1;
                else
                    errordlg("Error in setting the total number of records!",'Error Message','error')
                    return
                end

            end

            d.Value=1;
            d.Title='Initialization succeeded!';
            d.Message='Check Review Helper to start';
            pause(1)

            close(d)

            % Open HkcPick_QuickReviewHelper Tool
            runReviewHelperTool(app);


        end

        % Button pushed function: EnlargeFigSizeButton
        function EnlargeFigSizeButtonPushed(app, event)
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
            EnlargeFigs(app);
        end

        % Button pushed function: ResetFigSizeButton
        function ResetFigSizeButtonPushed(app, event)
            % Reduce App window size to trigger auto adjustion
            ss = get(0,'ScreenSize');
            ss(1)=100;
            ss(2)=100;
            ss(3)=round(ss(3)/2);
            ss(4)=round(ss(4)/2);
            app.UIFigure.Position=ss;
            pause(0.7)
            % Resize to full screen
            ss = get(0,'ScreenSize');
            app.UIFigure.Position=ss;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Write Change Log
            fileID=fopen(fullfile(app.Hkc_pick_app_dir,"ChangeLog.txt"),'a');

            %fprintf(fileID,"%d %s\n",size(app.HkcPick_tableall,1),'records saved!');
            fprintf(fileID,"%s %s\n",'[End Run] HkcPick_4QuickReview at: ',datestr(now,0));
            fclose(fileID);

            delete(app.HelperApp)
            delete(app)

        end

        % Menu selected function: OpenReviewHelperMenu
        function OpenReviewHelperMenuSelected(app, event)
            runReviewHelperTool(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenReviewHelperMenu = uimenu(app.UIFigure);
            app.OpenReviewHelperMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenReviewHelperMenuSelected, true);
            app.OpenReviewHelperMenu.Text = '   Open  Review Helper   ';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.M1_2 = uiaxes(app.GridLayout);
            app.M1_2.Visible = 'off';
            app.M2_2 = uiaxes(app.GridLayout);
            app.M2_2.Visible = 'off';
            app.M2_3 = uiaxes(app.GridLayout);
            app.M2_3.Visible = 'off';
            app.Ps_4 = uiaxes(app.GridLayout);
            app.Ps_4.Visible = 'off';
            app.M1_4 = uiaxes(app.GridLayout);
            app.M1_4.Visible = 'off';
            app.M2_4 = uiaxes(app.GridLayout);
            app.M2_4.Visible = 'off';
            app.Ps_5 = uiaxes(app.GridLayout);
            app.Ps_5.Visible = 'off';
            app.M1_5 = uiaxes(app.GridLayout);
            app.M1_5.Visible = 'off';
            app.allbefore = uiaxes(app.GridLayout);
            app.allbefore.Visible = 'off';
            app.hkbefore = uiaxes(app.GridLayout);
            app.hkbefore.Visible = 'off';
            app.Ps_1 = uiaxes(app.GridLayout);
            app.Ps_1.Visible = 'off';
            app.M2_5 = uiaxes(app.GridLayout);
            app.M2_5.Visible = 'off';
            app.Hk_5 = uiaxes(app.GridLayout);
            app.Hk_5.Visible = 'off';
            app.Hk_4 = uiaxes(app.GridLayout);
            app.Hk_4.Visible = 'off';
            app.Hk_3 = uiaxes(app.GridLayout);
            app.Hk_3.Visible = 'off';
            app.Hk_2 = uiaxes(app.GridLayout);
            app.Hk_2.Visible = 'off';
            app.Hk_1 = uiaxes(app.GridLayout);
            app.Hk_1.Visible = 'off';
            app.M1_3 = uiaxes(app.GridLayout);
            app.M1_3.Visible = 'off';
            app.Ps_3 = uiaxes(app.GridLayout);
            app.Ps_3.Visible = 'off';
            app.Ps_2 = uiaxes(app.GridLayout);
            app.Ps_2.Visible = 'off';
            app.M2_1 = uiaxes(app.GridLayout);
            app.M2_1.Visible = 'off';
            app.M1_1 = uiaxes(app.GridLayout);
            app.M1_1.Visible = 'off';
            app.TotalNumLabel = uilabel(app.GridLayout);
            app.TotalNumLabel.Text = 'TotalNum';
            app.ProjectInformationLabel = uilabel(app.GridLayout);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.StnumSpinner = uispinner(app.GridLayout);
            app.StnumSpinner.Editable = 'off';
            app.StnumSpinner.Enable = 'off';
            app.Run1Button = uibutton(app.GridLayout, 'state');
            app.Run1Button.Enable = 'off';
            app.Run1Button.Text = 'Run1';
            app.Run3Button = uibutton(app.GridLayout, 'state');
            app.Run3Button.Enable = 'off';
            app.Run3Button.Text = 'Run3';
            app.Run4Button = uibutton(app.GridLayout, 'state');
            app.Run4Button.Enable = 'off';
            app.Run4Button.Text = 'Run4';
            app.Run5Button = uibutton(app.GridLayout, 'state');
            app.Run5Button.Enable = 'off';
            app.Run5Button.Text = 'Run5';
            app.Run2Button = uibutton(app.GridLayout, 'state');
            app.Run2Button.Enable = 'off';
            app.Run2Button.Text = 'Run2';
            app.EnlargeFigSizeButton = uibutton(app.GridLayout, 'push');
            app.EnlargeFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFigSizeButtonPushed, true);
            app.EnlargeFigSizeButton.Tooltip = {'Enlarge the picture proportionally.'};
            app.EnlargeFigSizeButton.Text = 'Enlarge FigSize';
            app.ResetFigSizeButton = uibutton(app.GridLayout, 'push');
            app.ResetFigSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFigSizeButtonPushed, true);
            app.ResetFigSizeButton.Tooltip = {'When the image is enlarged too much, restore it to its original size.'};
            app.ResetFigSizeButton.Text = 'Reset FigSize';
            app.SummaryLabel = uilabel(app.GridLayout);
            app.SummaryLabel.Visible = 'off';
            app.SummaryLabel.Text = 'Summary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_4QuickReview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end