classdef HkcPick_QuickReviewHelper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControllingPanel               matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        AutomaticallySavetheRestResultsButton  matlab.ui.control.Button
        MergeCurrentHkcFigsButton      matlab.ui.control.Button
        MergeHkcfiguresintonewdirCheckBox  matlab.ui.control.CheckBox
        SaveCurrentUIFigureButton      matlab.ui.control.Button
        AutomaticallySaveallStationsLabel  matlab.ui.control.Label
        SettingsforSavingFiguresLabel  matlab.ui.control.Label
        SearchStationIndexbyStationCodeLabel  matlab.ui.control.Label
        SaveUIFigurestoselectedpathCheckBox  matlab.ui.control.CheckBox
        ImageSavingPathEditField       matlab.ui.control.EditField
        ImageSavingPathEditFieldLabel  matlab.ui.control.Label
        SelectDirforSavingButton       matlab.ui.control.Button
        ST_IDXLabel                    matlab.ui.control.Label
        STNMLabel                      matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        SearchforEditField             matlab.ui.control.EditField
        SearchforLabel                 matlab.ui.control.Label
        StnumSpinner0                  matlab.ui.control.Spinner
        sta_idxLabel                   matlab.ui.control.Label
        TotalNumLabel0                 matlab.ui.control.Label
        GotoButton                     matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end


    properties (Access = private)
        CallingApp   % Main app object
        image_saving_dir = ""
        image_type = ".png"
        save_image_aftergoto = false
        merge_image_aftergoto = false
    end

    properties (Access = public)
        pick_table
        is_fail = false
    end

    methods (Access = private)

        function UpdateTable(app)
            app.UITable.Data=app.pick_table(:,{'stnm','lon','lat','datasource','run_idx','tPs','Hafter','kappaafter'});
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, PickTable)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.pick_table = PickTable;

            %Get Total Number of Records
            app.TotalNumLabel0.Text=app.CallingApp.TotalNumLabel.Text;
            app.StnumSpinner0.Limits=app.CallingApp.StnumSpinner.Limits;
            app.StnumSpinner0.Value=app.CallingApp.StnumSpinner.Value;

            %Update Table Content
            UpdateTable(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OpenReviewHelperMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            if(app.save_image_aftergoto || app.merge_image_aftergoto)
                if(app.image_saving_dir=="" || app.image_saving_dir=="0")
                    warndlg(["Please select a path to save figures.";"(Or cancel the selected state of any figure-saving checkbox.)"],'Warning');
                    return
                end
            end

            % Check passed. Update.
            app.CallingApp.StnumSpinner.Value=app.StnumSpinner0.Value;

            UpdateFigures(app.CallingApp);

            % Save images
            if(app.save_image_aftergoto)
                if(exist(app.image_saving_dir,'dir'))
                    SaveOneFigure(app.CallingApp,app.image_saving_dir,app.image_type);
                else
                    errordlg("Could not find image saving path!",'Error Message','error')
                    return
                end
            end
            % Merge figures
            if(app.merge_image_aftergoto)
                if(exist(app.image_saving_dir,'dir'))
                    MergeOneStation(app.CallingApp,app.image_saving_dir);
                else
                    errordlg("Could not find image saving path!",'Error Message','error')
                    return
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            search_item=app.SearchforEditField.Value;
            app.STNMLabel.Text=string(search_item)+"_idx:";
            try
                if(sum(app.pick_table.stnm==string(search_item))==0)
                    app.ST_IDXLabel.Text="NaN";
                else
                    app.ST_IDXLabel.Text=string(app.pick_table.sta_idx(app.pick_table.stnm==string(search_item),1));
                end
            catch ME
                errordlg(["Error in Searching for "+string(search_item)+"! Error Message:";string(ME)],'Error Message','error')
                return
            end

        end

        % Button pushed function: SelectDirforSavingButton
        function SelectDirforSavingButtonPushed(app, event)
            app.image_saving_dir=uigetdir(pwd,'Select a Directory to saving images');
            app.ImageSavingPathEditField.Value=string(app.image_saving_dir);
            app.ImageSavingPathEditField.Tooltip=app.image_saving_dir;
        end

        % Value changed function: 
        % SaveUIFigurestoselectedpathCheckBox
        function SaveUIFigurestoselectedpathCheckBoxValueChanged(app, event)
            value = app.SaveUIFigurestoselectedpathCheckBox.Value;
            if(value==0)
                app.save_image_aftergoto=false;
            elseif(value==1)
                app.save_image_aftergoto=true;
            else
                errordlg("Error in setting whether images to be saved.",'Error Message','error')
                return
            end
        end

        % Button pushed function: SaveCurrentUIFigureButton
        function SaveCurrentUIFigureButtonPushed(app, event)
            SaveOneFigure(app.CallingApp,app.image_saving_dir,app.image_type);
        end

        % Value changed function: MergeHkcfiguresintonewdirCheckBox
        function MergeHkcfiguresintonewdirCheckBoxValueChanged(app, event)
            value = app.MergeHkcfiguresintonewdirCheckBox.Value;
            if(value==0)
                app.merge_image_aftergoto=false;
            elseif(value==1)
                app.merge_image_aftergoto=true;
            else
                errordlg("Error in setting whether images to be merged.",'Error Message','error')
                return
            end
        end

        % Button pushed function: MergeCurrentHkcFigsButton
        function MergeCurrentHkcFigsButtonPushed(app, event)
            MergeOneStation(app.CallingApp,app.image_saving_dir);
        end

        % Button pushed function: 
        % AutomaticallySavetheRestResultsButton
        function AutomaticallySavetheRestResultsButtonPushed(app, event)
            msg = ["Current State:";
                "Merge Hkc Images?: " + string(app.merge_image_aftergoto);
                "Save UIFigure? " + string(app.save_image_aftergoto);
                "Saving/Merging Path: " + string(app.image_saving_dir);
                "(And make sure current FigSize in HkcPick_4QuickReview is optimal)"];
            title = 'Confirm';
            selection = uiconfirm(app.UIFigure,msg,title, ...
                'Options',{'Yes','No'}, ...
                'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    % do not return
                case 'No'
                    return
            end

            % Start to loop
            d = uiprogressdlg(app.UIFigure,'Title','Auto saving/merging...',...
                'Message','Starting...','Cancelable','on','Indeterminate','on');
            
            for n=max(app.StnumSpinner0.Value,1):size(app.pick_table,1)
                pause(0.5)
                % update current station
                app.StnumSpinner0.Value=n;
                % d.Value = (n-max(app.StnumSpinner0.Value,1))/size(app.pick_table,1);
                d.Message = sprintf('Working on No.%d of %d stations. Current station name: %s',n,size(app.pick_table,1),app.pick_table.stnm(n));
                % go to current station
                app.is_fail = false;
                try
                    GotoButtonPushed(app);
                    % Check for Cancel button press
                    if d.CancelRequested
                        break
                    end
                catch ME
                    errordlg(["Error in auto saving/merging figures! Error Message:";ME.message],'Error Message','error')
                    fileID=fopen(fullfile(app.image_saving_dir,'auto_fail.txt'),'a');
                    fprintf(fileID,'[Fail] %s',app.pick_table.stnm(n));
                    fclose(fileID);
                    continue
                end
                
                if(app.is_fail==true)
                    fileID=fopen(fullfile(app.image_saving_dir,'auto_fail.txt'),'a');
                    fprintf(fileID,'[Fail] %s',app.pick_table.stnm(n));
                    fclose(fileID);
                end
                
            end
            d.Value=1;
            d.Message='Finishing';
            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'stnm'; 'lon'; 'lat'; 'datasource'; 'run_idx'; 'tPs'; 'Hafter'; 'kappaafter'};
            app.UITable.RowName = {};
            app.ControllingPanel = uipanel(app.UIFigure);
            app.ControllingPanel.Title = 'Controlling Panel';
            app.GridLayout = uigridlayout(app.ControllingPanel);
            app.GotoButton = uibutton(app.GridLayout, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Go to';
            app.TotalNumLabel0 = uilabel(app.GridLayout);
            app.sta_idxLabel = uilabel(app.GridLayout);
            app.sta_idxLabel.Text = 'sta_idx';
            app.StnumSpinner0 = uispinner(app.GridLayout);
            app.SearchforLabel = uilabel(app.GridLayout);
            app.SearchforLabel.Text = {'Search'; 'for'};
            app.SearchforEditField = uieditfield(app.GridLayout, 'text');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>>';
            app.STNMLabel = uilabel(app.GridLayout);
            app.STNMLabel.Text = 'STNM';
            app.ST_IDXLabel = uilabel(app.GridLayout);
            app.ST_IDXLabel.Text = 'ST_IDX';
            app.SelectDirforSavingButton = uibutton(app.GridLayout, 'push');
            app.SelectDirforSavingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirforSavingButtonPushed, true);
            app.SelectDirforSavingButton.Text = {'Select Dir '; 'for Saving'};
            app.ImageSavingPathEditFieldLabel = uilabel(app.GridLayout);
            app.ImageSavingPathEditFieldLabel.Text = {'Image'; 'Saving'; 'Path'};
            app.ImageSavingPathEditField = uieditfield(app.GridLayout, 'text');
            app.ImageSavingPathEditField.Editable = 'off';
            app.SaveUIFigurestoselectedpathCheckBox = uicheckbox(app.GridLayout);
            app.SaveUIFigurestoselectedpathCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveUIFigurestoselectedpathCheckBoxValueChanged, true);
            app.SaveUIFigurestoselectedpathCheckBox.Text = 'Save UIFigures to selected path';
            app.SearchStationIndexbyStationCodeLabel = uilabel(app.GridLayout);
            app.SearchStationIndexbyStationCodeLabel.Text = 'Search Station Index by Station Code';
            app.SettingsforSavingFiguresLabel = uilabel(app.GridLayout);
            app.SettingsforSavingFiguresLabel.Tooltip = {'To get better UIfigure outputs, check whether "Enlarge Figsize" is needed in HkcPick4_QuickReview'};
            app.SettingsforSavingFiguresLabel.Text = 'Settings for Saving Figures';
            app.AutomaticallySaveallStationsLabel = uilabel(app.GridLayout);
            app.AutomaticallySaveallStationsLabel.Text = 'Automatically Save all Stations';
            app.SaveCurrentUIFigureButton = uibutton(app.GridLayout, 'push');
            app.SaveCurrentUIFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentUIFigureButtonPushed, true);
            app.SaveCurrentUIFigureButton.Text = {'Save '; 'Current '; 'UIFigure'};
            app.MergeHkcfiguresintonewdirCheckBox = uicheckbox(app.GridLayout);
            app.MergeHkcfiguresintonewdirCheckBox.ValueChangedFcn = createCallbackFcn(app, @MergeHkcfiguresintonewdirCheckBoxValueChanged, true);
            app.MergeHkcfiguresintonewdirCheckBox.Text = 'Merge Hkc figures into new dir';
            app.MergeCurrentHkcFigsButton = uibutton(app.GridLayout, 'push');
            app.MergeCurrentHkcFigsButton.ButtonPushedFcn = createCallbackFcn(app, @MergeCurrentHkcFigsButtonPushed, true);
            app.MergeCurrentHkcFigsButton.Text = {'Merge'; 'Current'; 'HkcFigs'};
            app.AutomaticallySavetheRestResultsButton = uibutton(app.GridLayout, 'push');
            app.AutomaticallySavetheRestResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticallySavetheRestResultsButtonPushed, true);
            app.AutomaticallySavetheRestResultsButton.Text = 'Automatically Save the Rest Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_QuickReviewHelper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end