classdef HkcPick_TextAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveMarkingMenu              matlab.ui.container.Menu
        GenerateNoteMenu             matlab.ui.container.Menu
        EnablePlanSelectionMenu      matlab.ui.container.Menu
        ManualMarkingPanel           matlab.ui.container.Panel
        NoteLabel                    matlab.ui.control.Label
        NoteTextManual               matlab.ui.control.TextArea
        NoteCheckBox                 matlab.ui.control.CheckBox
        DemoSpinner                  matlab.ui.control.Spinner
        DemoSpinnerLabel             matlab.ui.control.Label
        ReRunCheckBox                matlab.ui.control.CheckBox
        HkcQualityButtonGroup        matlab.ui.container.ButtonGroup
        HkcQuality2plus              matlab.ui.control.RadioButton
        HkcQuality2minus             matlab.ui.control.RadioButton
        HkcQuality1plus              matlab.ui.control.RadioButton
        HkcQuality1minus             matlab.ui.control.RadioButton
        HkcQuality0                  matlab.ui.control.RadioButton
        M2RangeButtonGroup           matlab.ui.container.ButtonGroup
        M2Range1plus                 matlab.ui.control.RadioButton
        M2Range1minus                matlab.ui.control.RadioButton
        M2Range0                     matlab.ui.control.RadioButton
        M2ClarityButtonGroup         matlab.ui.container.ButtonGroup
        M2Clarity1plus               matlab.ui.control.RadioButton
        M2Clarity1minus              matlab.ui.control.RadioButton
        M2Clarity0                   matlab.ui.control.RadioButton
        M1RangeButtonGroup           matlab.ui.container.ButtonGroup
        M1Range1plus                 matlab.ui.control.RadioButton
        M1Range1minus                matlab.ui.control.RadioButton
        M1Range0                     matlab.ui.control.RadioButton
        M1ClarityButtonGroup         matlab.ui.container.ButtonGroup
        M1Clarity1plus               matlab.ui.control.RadioButton
        M1Clarity1minus              matlab.ui.control.RadioButton
        M1Clarity0                   matlab.ui.control.RadioButton
        PsRangeButtonGroup           matlab.ui.container.ButtonGroup
        PsRange1plus                 matlab.ui.control.RadioButton
        PsRange1minus                matlab.ui.control.RadioButton
        PsRange0                     matlab.ui.control.RadioButton
        PsClarityButtonGroup         matlab.ui.container.ButtonGroup
        PsClarity1plus               matlab.ui.control.RadioButton
        PsClarity1minus              matlab.ui.control.RadioButton
        PsClarity0                   matlab.ui.control.RadioButton
        APrioriPanel                 matlab.ui.container.Panel
        kappaClusteringSpinner       matlab.ui.control.Spinner
        kappaClusteringSpinnerLabel  matlab.ui.control.Label
        HClusteringSpinner           matlab.ui.control.Spinner
        HClusteringSpinnerLabel      matlab.ui.control.Label
        BAZDeficiencyEditField       matlab.ui.control.NumericEditField
        BAZDeficiencyEditFieldLabel  matlab.ui.control.Label
        PlanSelectionButtonGroup     matlab.ui.container.ButtonGroup
        Run5Button                   matlab.ui.control.RadioButton
        Run4Button                   matlab.ui.control.RadioButton
        Run3Button                   matlab.ui.control.RadioButton
        Run2Button                   matlab.ui.control.RadioButton
        Run1Button                   matlab.ui.control.RadioButton
        dk_clusteringEditField       matlab.ui.control.NumericEditField
        dk_clusteringEditFieldLabel  matlab.ui.control.Label
        dH_clusteringEditField       matlab.ui.control.NumericEditField
        dH_clusteringEditFieldLabel  matlab.ui.control.Label
        kafterkrefEditField          matlab.ui.control.NumericEditField
        kafterkrefEditFieldLabel     matlab.ui.control.Label
        HafterHrefEditField          matlab.ui.control.NumericEditField
        HafterHrefEditFieldLabel     matlab.ui.control.Label
        UITable4                     matlab.ui.control.Table
        ResultsPanel                 matlab.ui.container.Panel
        UITable3                     matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        StabilityAnalysisPanel       matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
    end


    properties (Access = private)
        CallingApp   % Main app object
        datadir
        baz_deficiency
        baz_comparepattern
    end

    properties (Access = public)
        pick_table_now
    end


    methods (Access = public)

        function UpdateStabilityTable(app)

            app.pick_table_now=app.CallingApp.pick_table_now;

            a=length(app.UITable.ColumnName);
            b=length(app.UITable.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable.ColumnName,'RowNames',app.UITable.RowName);

            t('H','Raw STD')=app.pick_table_now(1,'H_stdraw');
            t('H','Normalized STD')=app.pick_table_now(1,'H_stdnorm');

            t('kappa','Raw STD')=app.pick_table_now(1,'kappa_stdraw');
            t('kappa','Normalized STD')=app.pick_table_now(1,'kappa_stdnorm');

            t('PsTime','Raw STD')=app.pick_table_now(1,'tPs_stdraw');
            t('PsTime','Normalized STD')=app.pick_table_now(1,'tPs_stdnorm');

            t('M1Time','Raw STD')=app.pick_table_now(1,'tM1_stdraw');
            t('M1Time','Normalized STD')=app.pick_table_now(1,'tM1_stdnorm');

            t('M2Time','Raw STD')=app.pick_table_now(1,'tM2_stdraw');
            t('M2Time','Normalized STD')=app.pick_table_now(1,'tM2_stdnorm');

            t('PsA1','Raw STD')=app.pick_table_now(1,'PsA1_stdraw');
            t('PsA1','Normalized STD')=app.pick_table_now(1,'PsA1_stdnorm');
            t('PsA2','Raw STD')=app.pick_table_now(1,'PsA2_stdraw');
            t('PsA2','Normalized STD')=app.pick_table_now(1,'PsA2_stdnorm');

            t('M1A1','Raw STD')=app.pick_table_now(1,'M1A1_stdraw');
            t('M1A1','Normalized STD')=app.pick_table_now(1,'tM2_stdnorm');
            t('M1A2','Raw STD')=app.pick_table_now(1,'M1A2_stdraw');
            t('M1A2','Normalized STD')=app.pick_table_now(1,'M1A2_stdnorm');

            t('M2A1','Raw STD')=app.pick_table_now(1,'M2A1_stdraw');
            t('M2A1','Normalized STD')=app.pick_table_now(1,'M2A1_stdnorm');
            t('M2A2','Raw STD')=app.pick_table_now(1,'M2A2_stdraw');
            t('M2A2','Normalized STD')=app.pick_table_now(1,'M2A2_stdnorm');

            t('PsTheta1_sig','Raw STD')=app.pick_table_now(1,'PsTheta1_sigmastdraw');
            t('PsTheta1_sig','Normalized STD')=app.pick_table_now(1,'PsTheta1_sigmastdnorm');
            t('PsTheta1_rho','Raw STD')=app.pick_table_now(1,'PsTheta1_rhostdraw');
            t('PsTheta1_rho','Normalized STD')=app.pick_table_now(1,'PsTheta1_rhostdnorm');
            t('PsTheta1_STD','Raw STD')=app.pick_table_now(1,'PsTheta1_stdnorm');
            t('PsTheta1_STD','Normalized STD')=app.pick_table_now(1,'PsTheta1_stdnorm');
            t('PsTheta2_sig','Raw STD')=app.pick_table_now(1,'PsTheta2_sigmastdraw');
            t('PsTheta2_sig','Normalized STD')=app.pick_table_now(1,'PsTheta2_sigmastdnorm');
            t('PsTheta2_rho','Raw STD')=app.pick_table_now(1,'PsTheta2_rhostdraw');
            t('PsTheta2_rho','Normalized STD')=app.pick_table_now(1,'PsTheta2_rhostdnorm');
            t('PsTheta2_STD','Raw STD')=app.pick_table_now(1,'PsTheta2_stdnorm');
            t('PsTheta2_STD','Normalized STD')=app.pick_table_now(1,'PsTheta2_stdnorm');

            t('M1Theta1_sig','Raw STD')=app.pick_table_now(1,'M1Theta1_sigmastdraw');
            t('M1Theta1_sig','Normalized STD')=app.pick_table_now(1,'M1Theta1_sigmastdnorm');
            t('M1Theta1_rho','Raw STD')=app.pick_table_now(1,'M1Theta1_rhostdraw');
            t('M1Theta1_rho','Normalized STD')=app.pick_table_now(1,'M1Theta1_rhostdnorm');
            t('M1Theta1_STD','Raw STD')=app.pick_table_now(1,'M1Theta1_stdnorm');
            t('M1Theta1_STD','Normalized STD')=app.pick_table_now(1,'M1Theta1_stdnorm');
            t('M1Theta2_sig','Raw STD')=app.pick_table_now(1,'M1Theta2_sigmastdraw');
            t('M1Theta2_sig','Normalized STD')=app.pick_table_now(1,'M1Theta2_sigmastdnorm');
            t('M1Theta2_rho','Raw STD')=app.pick_table_now(1,'M1Theta2_rhostdraw');
            t('M1Theta2_rho','Normalized STD')=app.pick_table_now(1,'M1Theta2_rhostdnorm');
            t('M1Theta2_STD','Raw STD')=app.pick_table_now(1,'M1Theta2_stdnorm');
            t('M1Theta2_STD','Normalized STD')=app.pick_table_now(1,'M1Theta2_stdnorm');

            t('M2Theta1_sig','Raw STD')=app.pick_table_now(1,'M2Theta1_sigmastdraw');
            t('M2Theta1_sig','Normalized STD')=app.pick_table_now(1,'M2Theta1_sigmastdnorm');
            t('M2Theta1_rho','Raw STD')=app.pick_table_now(1,'M2Theta1_rhostdraw');
            t('M2Theta1_rho','Normalized STD')=app.pick_table_now(1,'M2Theta1_rhostdnorm');
            t('M2Theta1_STD','Raw STD')=app.pick_table_now(1,'M2Theta1_stdnorm');
            t('M2Theta1_STD','Normalized STD')=app.pick_table_now(1,'M2Theta1_stdnorm');
            t('M2Theta2_sig','Raw STD')=app.pick_table_now(1,'M2Theta2_sigmastdraw');
            t('M2Theta2_sig','Normalized STD')=app.pick_table_now(1,'M2Theta2_sigmastdnorm');
            t('M2Theta2_rho','Raw STD')=app.pick_table_now(1,'M2Theta2_rhostdraw');
            t('M2Theta2_rho','Normalized STD')=app.pick_table_now(1,'M2Theta2_rhostdnorm');
            t('M2Theta2_STD','Raw STD')=app.pick_table_now(1,'M2Theta2_stdnorm');
            t('M2Theta2_STD','Normalized STD')=app.pick_table_now(1,'M2Theta2_stdnorm');

            t('H_before','Raw STD')=app.pick_table_now(1,'Hbefore_check');
            t('H_before','Normalized STD')={NaN};
            t('kappa_before','Raw STD')=app.pick_table_now(1,'kappabefore_check');
            t('kappa_before','Normalized STD')={NaN};

            app.UITable.Data=t;
        end


        function UpdateResultTable(app)

            app.pick_table_now=app.CallingApp.pick_table_now;

            a=length(app.UITable2.ColumnName);
            b=length(app.UITable2.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable2.ColumnName,'RowNames',app.UITable2.RowName);

            t('Ps','Time')=app.pick_table_now(1,'tPs');
            t('Ps','A1')=app.pick_table_now(1,'PsA1');
            t('Ps','Theta1')=app.pick_table_now(1,'PsTheta1');
            t('Ps','Theta2')=app.pick_table_now(1,'PsTheta2');
            t('Ps','A2')=app.pick_table_now(1,'PsA2');

            t('M1','Time')=app.pick_table_now(1,'tM1');
            t('M1','A1')=app.pick_table_now(1,'M1A1');
            t('M1','Theta1')=app.pick_table_now(1,'M1Theta1');
            t('M1','Theta2')=app.pick_table_now(1,'M1Theta2');
            t('M1','A2')=app.pick_table_now(1,'M1A2');

            t('M2','Time')=app.pick_table_now(1,'tM2');
            t('M2','A1')=app.pick_table_now(1,'M2A1');
            t('M2','Theta1')=app.pick_table_now(1,'M2Theta1');
            t('M2','Theta2')=app.pick_table_now(1,'M2Theta2');
            t('M2','A2')=app.pick_table_now(1,'M2A2');

            app.UITable2.Data=t;

            clear t

            a=length(app.UITable3.ColumnName);
            b=length(app.UITable3.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable3.ColumnName,'RowNames',app.UITable3.RowName);

            t('after','H')=app.pick_table_now(1,'Hafter');
            t("after",'kappa')=app.pick_table_now(1,'kappaafter');
            t('before','H')=app.pick_table_now(1,'Hbefore');
            t("before",'kappa')=app.pick_table_now(1,'kappabefore');

            app.UITable3.Data=t;

        end

        function UpdateAprioriTable(app)

            app.pick_table_now=app.CallingApp.pick_table_now;

            a=length(app.UITable4.ColumnName);
            b=length(app.UITable4.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable4.ColumnName,'RowNames',app.UITable4.RowName);

            t("M1Ps_ratio","Harmonic Search")=app.pick_table_now(1,'M1Psratio_c');
            t("M1Ps_ratio","hk formula")=app.pick_table_now(1,'M1Psratio_hk');
            t("M1Ps_ratio",'difference')=app.pick_table_now(1,'dM1Psratio_hkc');
            t("PsM1_M2","Harmonic Search")=app.pick_table_now(1,'PsM1M2_c');
            t("PsM1_M2","hk formula")=app.pick_table_now(1,'PsM1M2_hk');
            t("PsM1_M2","difference")=app.pick_table_now(1,'dPsM1M2_hkc');

            t("tPs","Harmonic Search")=app.pick_table_now(1,'tPs');
            t("tPs","hk formula")=app.pick_table_now(1,'tPs_hk');
            t("tPs","difference")=app.pick_table_now(1,'dtPs_hkc');
            t("tM1","Harmonic Search")=app.pick_table_now(1,'tM1');
            t("tM1","hk formula")=app.pick_table_now(1,'tM1_hk');
            t("tM1","difference")=app.pick_table_now(1,'dtM1_hkc');
            t("tM2","Harmonic Search")=app.pick_table_now(1,'tM2');
            t("tM2","hk formula")=app.pick_table_now(1,'tM2_hk');
            t("tM2","difference")=app.pick_table_now(1,'dtM2_hkc');

            app.UITable4.Data=t;

            if(isnan(app.pick_table_now.dH_hkc))
                app.HafterHrefEditField.Value=-99999;
            else
                app.HafterHrefEditField.Value=table2array(app.pick_table_now(1,'dH_hkc'));
            end
            
            if(isnan(app.pick_table_now.dkappa_hkc))
                app.kafterkrefEditField.Value=-99999;
            else
                app.kafterkrefEditField.Value=table2array(app.pick_table_now(1,'dkappa_hkc'));
            end

            app.NoteLabel.Text=string(table2array(app.pick_table_now(1,'stnm')));

            try
                app.pick_table_now(1,'BAZ_coverage')=app.baz_deficiency(matches(app.baz_comparepattern,string(table2array(app.pick_table_now(1,'stnm')))),'Var2');
                app.BAZDeficiencyEditField.Value=table2array(app.pick_table_now(1,'BAZ_coverage'));
            catch
                % do nothing
            end
        end


        function CleanAllTable(app)
            % Initialize
            a=length(app.UITable.ColumnName);
            b=length(app.UITable.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable.ColumnName,'RowNames',app.UITable.RowName);
            app.UITable.Data=t;

            a=length(app.UITable2.ColumnName);
            b=length(app.UITable2.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable2.ColumnName,'RowNames',app.UITable2.RowName);
            app.UITable2.Data=t;

            a=length(app.UITable3.ColumnName);
            b=length(app.UITable3.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable3.ColumnName,'RowNames',app.UITable3.RowName);
            app.UITable3.Data=t;

            a=length(app.UITable4.ColumnName);
            b=length(app.UITable4.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable4.ColumnName,'RowNames',app.UITable4.RowName);
            app.UITable4.Data=t;

            app.HafterHrefEditField.Value=Inf;
            app.kafterkrefEditField.Value=Inf;

            app.NoteLabel.Text="Wait to Pick ...";
            app.BAZDeficiencyEditField.Value=Inf;
            app.NoteTextManual.Value="";
            
            app.NoteCheckBox.Value=0;
            app.ReRunCheckBox.Value=0;
            
            app.HkcQualityButtonGroup.SelectedObject=app.HkcQuality0;
            app.M2RangeButtonGroup.SelectedObject=app.M2Range0;
            app.M2ClarityButtonGroup.SelectedObject=app.M2Clarity0;
            app.M1RangeButtonGroup.SelectedObject=app.M1Range0;
            app.M1ClarityButtonGroup.SelectedObject=app.M1Clarity0;
            app.PsRangeButtonGroup.SelectedObject=app.PsRange0;
            app.PsClarityButtonGroup.SelectedObject=app.PsClarity0;
            
            app.kappaClusteringSpinner.Value=0;
            app.HClusteringSpinner.Value=0;
            app.DemoSpinner.Value=0;
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, PickTable, Hkcdatadir)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.pick_table_now=PickTable;
            app.datadir=Hkcdatadir;

            % get baz deficiency
            if(exist(fullfile(app.datadir,'baz_absense.dat'),"file")==0)
                warndlg("Could not find baz_absense.dat, please input baz absense manually!",...
                    "File Missing")
            else
                opts = detectImportOptions(fullfile(app.datadir,'baz_absense.dat'),'ReadVariableNames',false,'Delimiter',{' '},'ConsecutiveDelimitersRule','join','FileType','Text');
                app.baz_deficiency = readtable(fullfile(app.datadir,'baz_absense.dat'),opts);
                app.baz_comparepattern=erase(string(app.baz_deficiency.Var1),"/");
            end

            % Initialize
            a=length(app.UITable.ColumnName);
            b=length(app.UITable.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable.ColumnName,'RowNames',app.UITable.RowName);
            app.UITable.Data=t;

            a=length(app.UITable2.ColumnName);
            b=length(app.UITable2.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable2.ColumnName,'RowNames',app.UITable2.RowName);
            app.UITable2.Data=t;

            a=length(app.UITable3.ColumnName);
            b=length(app.UITable3.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable3.ColumnName,'RowNames',app.UITable3.RowName);
            app.UITable3.Data=t;

            a=length(app.UITable4.ColumnName);
            b=length(app.UITable4.RowName);
            t=table('Size',[b,a],'VariableTypes',repmat({'double'},1,a),...
                'VariableNames',app.UITable4.ColumnName,'RowNames',app.UITable4.RowName);
            app.UITable4.Data=t;
        end

        % Selection changed function: PlanSelectionButtonGroup
        function PlanSelectionButtonGroupSelectionChanged(app, event)
            %assignin("base","pick_table",app.pick_table_now)
            selectedButton = app.PlanSelectionButtonGroup.SelectedObject.Text;
            PickAnalysisChangeRun(app.CallingApp,selectedButton)
        end

        % Menu selected function: EnablePlanSelectionMenu
        function EnablePlanSelectionMenuSelected(app, event)
            app.Run1Button.Enable='on';
            app.Run2Button.Enable='on';
            app.Run3Button.Enable='on';
            app.Run4Button.Enable='on';
            app.Run5Button.Enable='on';
        end

        % Selection changed function: PsClarityButtonGroup
        function PsClarityButtonGroupSelectionChanged(app, event)
            selectedButton = app.PsClarityButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'Ps_clarity')=array2table(-1,'VariableNames',{'Ps_clarity'});
                case '0'
                    app.pick_table_now(1,'Ps_clarity')=array2table(0,'VariableNames',{'Ps_clarity'});
                case '1'
                    app.pick_table_now(1,'Ps_clarity')=array2table(1,'VariableNames',{'Ps_clarity'});
                otherwise
                    warndlg("Unexpected option in Ps_clarity!",...
                        "Option Warning")
            end
        end

        % Menu selected function: SaveMarkingMenu
        function SaveMarkingMenuSelected(app, event)
            GenerateNoteMenuSelected(app);
            GetNowTableFromDialogApp(app.CallingApp);
        end

        % Selection changed function: M1ClarityButtonGroup
        function M1ClarityButtonGroupSelectionChanged(app, event)
            selectedButton = app.M1ClarityButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'M1_clarity')=array2table(-1,'VariableNames',{'M1_clarity'});
                case '0'
                    app.pick_table_now(1,'M1_clarity')=array2table(0,'VariableNames',{'M1_clarity'});
                case '1'
                    app.pick_table_now(1,'M1_clarity')=array2table(1,'VariableNames',{'M1_clarity'});
                otherwise
                    warndlg("Unexpected option in M1_clarity!",...
                        "Option Warning")
            end
        end

        % Selection changed function: M2ClarityButtonGroup
        function M2ClarityButtonGroupSelectionChanged(app, event)
            selectedButton = app.M2ClarityButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'M2_clarity')=array2table(-1,'VariableNames',{'M2_clarity'});
                case '0'
                    app.pick_table_now(1,'M2_clarity')=array2table(0,'VariableNames',{'M2_clarity'});
                case '1'
                    app.pick_table_now(1,'M2_clarity')=array2table(1,'VariableNames',{'M2_clarity'});
                otherwise
                    warndlg("Unexpected option in M2_clarity!",...
                        "Option Warning")
            end
        end

        % Selection changed function: PsRangeButtonGroup
        function PsRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PsRangeButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'Ps_Arange')=array2table(-1,'VariableNames',{'Ps_Arange'});
                case '0'
                    app.pick_table_now(1,'Ps_Arange')=array2table(0,'VariableNames',{'Ps_Arange'});
                case '1'
                    app.pick_table_now(1,'Ps_Arange')=array2table(1,'VariableNames',{'Ps_Arange'});
                otherwise
                    warndlg("Unexpected option in Ps_Arange!",...
                        "Option Warning")
            end
        end

        % Selection changed function: M1RangeButtonGroup
        function M1RangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.M1RangeButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'M1_Arange')=array2table(-1,'VariableNames',{'M1_Arange'});
                case '0'
                    app.pick_table_now(1,'M1_Arange')=array2table(0,'VariableNames',{'M1_Arange'});
                case '1'
                    app.pick_table_now(1,'M1_Arange')=array2table(1,'VariableNames',{'M1_Arange'});
                otherwise
                    warndlg("Unexpected option in M1_Arange!",...
                        "Option Warning")
            end
        end

        % Selection changed function: M2RangeButtonGroup
        function M2RangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.M2RangeButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-1'
                    app.pick_table_now(1,'M2_Arange')=array2table(-1,'VariableNames',{'M2_Arange'});
                case '0'
                    app.pick_table_now(1,'M2_Arange')=array2table(0,'VariableNames',{'M2_Arange'});
                case '1'
                    app.pick_table_now(1,'M2_Arange')=array2table(1,'VariableNames',{'M2_Arange'});
                otherwise
                    warndlg("Unexpected option in M2_Arange!",...
                        "Option Warning")
            end
        end

        % Selection changed function: HkcQualityButtonGroup
        function HkcQualityButtonGroupSelectionChanged(app, event)
            selectedButton = app.HkcQualityButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '-2'
                    app.pick_table_now(1,'Hkcquality')=array2table(-2,'VariableNames',{'Hkcquality'});
                case '-1'
                    app.pick_table_now(1,'Hkcquality')=array2table(-1,'VariableNames',{'Hkcquality'});
                case '0'
                    app.pick_table_now(1,'Hkcquality')=array2table(0,'VariableNames',{'Hkcquality'});
                case '1'
                    app.pick_table_now(1,'Hkcquality')=array2table(1,'VariableNames',{'Hkcquality'});
                case '2'
                    app.pick_table_now(1,'Hkcquality')=array2table(2,'VariableNames',{'Hkcquality'});
                otherwise
                    warndlg("Unexpected option in Hkcquality!",...
                        "Option Warning")
            end
        end

        % Value changed function: ReRunCheckBox
        function ReRunCheckBoxValueChanged(app, event)
            value = app.ReRunCheckBox.Value;
            app.pick_table_now(1,'rerun')=array2table(value,'VariableNames',{'rerun'});
        end

        % Value changed function: NoteCheckBox
        function NoteCheckBoxValueChanged(app, event)
            value = app.NoteCheckBox.Value;
            app.pick_table_now(1,'isnote')=array2table(value,'VariableNames',{'isnote'});
        end

        % Value changed function: DemoSpinner
        function DemoSpinnerValueChanged(app, event)
            value = app.DemoSpinner.Value;
            app.pick_table_now(1,'demo')=array2table(value,'VariableNames',{'demo'});
        end

        % Menu selected function: GenerateNoteMenu
        function GenerateNoteMenuSelected(app, event)
            notenow="";
            
            if(app.pick_table_now.demo == -1)
                notenow=[notenow,"bad data demo"];
            end
            if(app.pick_table_now.Hkcquality == -2)
                notenow=[notenow,"bad Hkcquality"];
            end
            if(sum([abs(app.pick_table_now.Ps_Arange),abs(app.pick_table_now.M1_Arange),abs(app.pick_table_now.M1_Arange)],'omitnan')~=0)
                notenow=[notenow,"Change search range for Ps/M1/M2"];
            end
            if(abs(app.pick_table_now.dH_hkc)>10 || abs(app.pick_table_now.dkappa_hkc)>0.20)
                notenow=[notenow,"Check reference/output crust model consistency"];
            end
            notenow=[notenow,join(string(app.NoteTextManual.Value))];
            
            app.NoteLabel.Tooltip=[string(table2array(app.pick_table_now(1,'stnm'))),notenow];
            
            if(sum(strlength(notenow))>0)
                app.NoteCheckBox.Value=1;
                app.pick_table_now(1,'isnote')=array2table(app.NoteCheckBox.Value,'VariableNames',{'isnote'});
            else
                app.NoteCheckBox.Value=0;
            end
            
            app.pick_table_now(1,'note')=array2table(strjoin(notenow,' + '),'VariableNames',{'note'});
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            app.CallingApp.OpenPickAnalysisToolMenu.Enable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveMarkingMenu = uimenu(app.UIFigure);
            app.SaveMarkingMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMarkingMenuSelected, true);
            app.SaveMarkingMenu.Text = '   SaveMarking   ';
            app.GenerateNoteMenu = uimenu(app.UIFigure);
            app.GenerateNoteMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateNoteMenuSelected, true);
            app.GenerateNoteMenu.Text = '   Generate Note   ';
            app.EnablePlanSelectionMenu = uimenu(app.UIFigure);
            app.EnablePlanSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablePlanSelectionMenuSelected, true);
            app.EnablePlanSelectionMenu.Text = '   EnablePlanSelection   ';
            app.StabilityAnalysisPanel = uipanel(app.UIFigure);
            app.StabilityAnalysisPanel.Title = 'Stability Analysis';
            app.UITable = uitable(app.StabilityAnalysisPanel);
            app.UITable.ColumnName = {'Raw STD'; 'Normalized STD'};
            app.UITable.RowName = {'H'; 'kappa'; 'PsTime'; 'M1Time'; 'M2Time'; 'PsA1'; 'PsA2'; 'M1A1'; 'M1A2'; 'M2A1'; 'M2A2'; 'PsTheta1_sig'; 'PsTheta2_sig'; 'M1Theta1_sig'; 'M1Theta2_sig'; 'M2Theta1_sig'; 'M2Theta2_sig'; 'PsTheta1_rho'; 'PsTheta2_rho'; 'M1Theta1_rho'; 'M1Theta2_rho'; 'M2Theta1_rho'; 'M2Theta2_rho'; 'PsTheta1_STD'; 'PsTheta2_STD'; 'M1Theta1_STD'; 'M1Theta2_STD'; 'M2Theta1_STD'; 'M2Theta2_STD'; 'H_before'; 'kappa_before'};
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UITable2 = uitable(app.ResultsPanel);
            app.UITable2.ColumnName = {'Time'; 'A1'; 'A2'; 'Theta1'; 'Theta2'};
            app.UITable2.RowName = {'Ps'; 'M1'; 'M2'};
            app.UITable3 = uitable(app.ResultsPanel);
            app.UITable3.ColumnName = {'H'; 'kappa'};
            app.UITable3.RowName = {'before'; 'after'};
            app.APrioriPanel = uipanel(app.UIFigure);
            app.APrioriPanel.Title = 'A Priori';
            app.UITable4 = uitable(app.APrioriPanel);
            app.UITable4.ColumnName = {'Harmonic Search'; 'hk formula'; 'difference'};
            app.UITable4.RowName = {'M1Ps_ratio'; 'PsM1_M2'; 'tPs'; 'tM1'; 'tM2'};
            app.HafterHrefEditFieldLabel = uilabel(app.APrioriPanel);
            app.HafterHrefEditFieldLabel.Text = 'Hafter-Href';
            app.HafterHrefEditField = uieditfield(app.APrioriPanel, 'numeric');
            app.HafterHrefEditField.Editable = 'off';
            app.HafterHrefEditField.Value = Inf;
            app.kafterkrefEditFieldLabel = uilabel(app.APrioriPanel);
            app.kafterkrefEditFieldLabel.Text = 'kafter-kref';
            app.kafterkrefEditField = uieditfield(app.APrioriPanel, 'numeric');
            app.kafterkrefEditField.Editable = 'off';
            app.kafterkrefEditField.Value = Inf;
            app.dH_clusteringEditFieldLabel = uilabel(app.APrioriPanel);
            app.dH_clusteringEditFieldLabel.Text = 'dH_clustering';
            app.dH_clusteringEditField = uieditfield(app.APrioriPanel, 'numeric');
            app.dH_clusteringEditField.Editable = 'off';
            app.dH_clusteringEditField.Value = Inf;
            app.dk_clusteringEditFieldLabel = uilabel(app.APrioriPanel);
            app.dk_clusteringEditFieldLabel.Text = 'dk_clustering';
            app.dk_clusteringEditField = uieditfield(app.APrioriPanel, 'numeric');
            app.dk_clusteringEditField.Editable = 'off';
            app.dk_clusteringEditField.Value = Inf;
            app.PlanSelectionButtonGroup = uibuttongroup(app.APrioriPanel);
            app.PlanSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlanSelectionButtonGroupSelectionChanged, true);
            app.PlanSelectionButtonGroup.Title = 'Plan Selection';
            app.Run1Button = uiradiobutton(app.PlanSelectionButtonGroup);
            app.Run1Button.Text = 'Run1';
            app.Run1Button.Value = true;
            app.Run2Button = uiradiobutton(app.PlanSelectionButtonGroup);
            app.Run2Button.Text = 'Run2';
            app.Run3Button = uiradiobutton(app.PlanSelectionButtonGroup);
            app.Run3Button.Text = 'Run3';
            app.Run4Button = uiradiobutton(app.PlanSelectionButtonGroup);
            app.Run4Button.Text = 'Run4';
            app.Run5Button = uiradiobutton(app.PlanSelectionButtonGroup);
            app.Run5Button.Text = 'Run5';
            app.BAZDeficiencyEditFieldLabel = uilabel(app.APrioriPanel);
            app.BAZDeficiencyEditFieldLabel.Text = {'BAZ'; 'Deficiency'};
            app.BAZDeficiencyEditField = uieditfield(app.APrioriPanel, 'numeric');
            app.BAZDeficiencyEditField.Value = Inf;
            app.HClusteringSpinnerLabel = uilabel(app.APrioriPanel);
            app.HClusteringSpinnerLabel.Text = {'H'; 'Clustering'};
            app.HClusteringSpinner = uispinner(app.APrioriPanel);
            app.HClusteringSpinner.Limits = [-2 2];
            app.HClusteringSpinner.Tooltip = {'Range:-2,2'};
            app.kappaClusteringSpinnerLabel = uilabel(app.APrioriPanel);
            app.kappaClusteringSpinnerLabel.Text = {'kappa'; 'Clustering'};
            app.kappaClusteringSpinner = uispinner(app.APrioriPanel);
            app.kappaClusteringSpinner.Limits = [-2 2];
            app.kappaClusteringSpinner.Tooltip = {'Range:-2,2'};
            app.ManualMarkingPanel = uipanel(app.UIFigure);
            app.ManualMarkingPanel.Title = 'Manual Marking';
            app.PsClarityButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.PsClarityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PsClarityButtonGroupSelectionChanged, true);
            app.PsClarityButtonGroup.Tooltip = {'-1: blurring'; '0: ordinary'; '1: clear'};
            app.PsClarityButtonGroup.Title = 'Ps Clarity';
            app.PsClarity0 = uiradiobutton(app.PsClarityButtonGroup);
            app.PsClarity0.Text = '0';
            app.PsClarity0.Value = true;
            app.PsClarity1minus = uiradiobutton(app.PsClarityButtonGroup);
            app.PsClarity1minus.Text = '-1';
            app.PsClarity1plus = uiradiobutton(app.PsClarityButtonGroup);
            app.PsClarity1plus.Text = '1';
            app.PsRangeButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.PsRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PsRangeButtonGroupSelectionChanged, true);
            app.PsRangeButtonGroup.Tooltip = {'-1: fewer range needed'; '0: do not change'; '1: more range needed'};
            app.PsRangeButtonGroup.Title = 'Ps Range';
            app.PsRange0 = uiradiobutton(app.PsRangeButtonGroup);
            app.PsRange0.Text = '0';
            app.PsRange0.Value = true;
            app.PsRange1minus = uiradiobutton(app.PsRangeButtonGroup);
            app.PsRange1minus.Text = '-1';
            app.PsRange1plus = uiradiobutton(app.PsRangeButtonGroup);
            app.PsRange1plus.Text = '1';
            app.M1ClarityButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.M1ClarityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M1ClarityButtonGroupSelectionChanged, true);
            app.M1ClarityButtonGroup.Tooltip = {'-1: blurring'; '0: ordinary'; '1: clear'};
            app.M1ClarityButtonGroup.Title = 'M1 Clarity';
            app.M1Clarity0 = uiradiobutton(app.M1ClarityButtonGroup);
            app.M1Clarity0.Text = '0';
            app.M1Clarity0.Value = true;
            app.M1Clarity1minus = uiradiobutton(app.M1ClarityButtonGroup);
            app.M1Clarity1minus.Text = '-1';
            app.M1Clarity1plus = uiradiobutton(app.M1ClarityButtonGroup);
            app.M1Clarity1plus.Text = '1';
            app.M1RangeButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.M1RangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M1RangeButtonGroupSelectionChanged, true);
            app.M1RangeButtonGroup.Tooltip = {'-1: fewer range needed'; '0: do not change'; '1: more range needed'};
            app.M1RangeButtonGroup.Title = 'M1 Range';
            app.M1Range0 = uiradiobutton(app.M1RangeButtonGroup);
            app.M1Range0.Text = '0';
            app.M1Range0.Value = true;
            app.M1Range1minus = uiradiobutton(app.M1RangeButtonGroup);
            app.M1Range1minus.Text = '-1';
            app.M1Range1plus = uiradiobutton(app.M1RangeButtonGroup);
            app.M1Range1plus.Text = '1';
            app.M2ClarityButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.M2ClarityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M2ClarityButtonGroupSelectionChanged, true);
            app.M2ClarityButtonGroup.Tooltip = {'-1: blurring'; '0: ordinary'; '1: clear'};
            app.M2ClarityButtonGroup.Title = 'M2 Clarity';
            app.M2Clarity0 = uiradiobutton(app.M2ClarityButtonGroup);
            app.M2Clarity0.Text = '0';
            app.M2Clarity0.Value = true;
            app.M2Clarity1minus = uiradiobutton(app.M2ClarityButtonGroup);
            app.M2Clarity1minus.Text = '-1';
            app.M2Clarity1plus = uiradiobutton(app.M2ClarityButtonGroup);
            app.M2Clarity1plus.Text = '1';
            app.M2RangeButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.M2RangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M2RangeButtonGroupSelectionChanged, true);
            app.M2RangeButtonGroup.Tooltip = {'-1: fewer range needed'; '0: do not change'; '1: more range needed'};
            app.M2RangeButtonGroup.Title = 'M2 Range';
            app.M2Range0 = uiradiobutton(app.M2RangeButtonGroup);
            app.M2Range0.Text = '0';
            app.M2Range0.Value = true;
            app.M2Range1minus = uiradiobutton(app.M2RangeButtonGroup);
            app.M2Range1minus.Text = '-1';
            app.M2Range1plus = uiradiobutton(app.M2RangeButtonGroup);
            app.M2Range1plus.Text = '1';
            app.HkcQualityButtonGroup = uibuttongroup(app.ManualMarkingPanel);
            app.HkcQualityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HkcQualityButtonGroupSelectionChanged, true);
            app.HkcQualityButtonGroup.Title = 'Hkc Quality';
            app.HkcQuality0 = uiradiobutton(app.HkcQualityButtonGroup);
            app.HkcQuality0.Text = '0';
            app.HkcQuality0.Value = true;
            app.HkcQuality1minus = uiradiobutton(app.HkcQualityButtonGroup);
            app.HkcQuality1minus.Text = '-1';
            app.HkcQuality1plus = uiradiobutton(app.HkcQualityButtonGroup);
            app.HkcQuality1plus.Text = '1';
            app.HkcQuality2minus = uiradiobutton(app.HkcQualityButtonGroup);
            app.HkcQuality2minus.Text = '-2';
            app.HkcQuality2plus = uiradiobutton(app.HkcQualityButtonGroup);
            app.HkcQuality2plus.Text = '2';
            app.ReRunCheckBox = uicheckbox(app.ManualMarkingPanel);
            app.ReRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReRunCheckBoxValueChanged, true);
            app.ReRunCheckBox.Text = 'ReRun';
            app.DemoSpinnerLabel = uilabel(app.ManualMarkingPanel);
            app.DemoSpinnerLabel.Text = 'Demo';
            app.DemoSpinner = uispinner(app.ManualMarkingPanel);
            app.DemoSpinner.Limits = [-1 1];
            app.DemoSpinner.ValueChangedFcn = createCallbackFcn(app, @DemoSpinnerValueChanged, true);
            app.NoteCheckBox = uicheckbox(app.ManualMarkingPanel);
            app.NoteCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoteCheckBoxValueChanged, true);
            app.NoteCheckBox.Text = 'Note';
            app.NoteTextManual = uitextarea(app.ManualMarkingPanel);
            app.NoteTextManual.Tooltip = {'Input Here...'};
            app.NoteLabel = uilabel(app.ManualMarkingPanel);
            app.NoteLabel.Text = 'Note';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_TextAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end