classdef HkcPick_TimeSeperationCalculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RESULTTextArea       matlab.ui.control.TextArea
        RESULTTextAreaLabel  matlab.ui.control.Label
        CalculateButton      matlab.ui.control.Button
        kappaEditField       matlab.ui.control.NumericEditField
        kappaEditFieldLabel  matlab.ui.control.Label
        HEditField           matlab.ui.control.NumericEditField
        HEditFieldLabel      matlab.ui.control.Label
        Vp63Label            matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
                H=app.HEditField.Value;
                kappa=app.kappaEditField.Value;
                [tPs,tM1,tM2]=HkcPick_TimeSeperation(H,kappa);
                timesep_results(1)="INPUTs:";
                timesep_results(2)="H:     "+string(H);
                timesep_results(3)="kappa: "+string(kappa);
                timesep_results(4)=" ";
                timesep_results(5)="OUTPUTs:";
                timesep_results(6)="tPs:   "+string(tPs);
                timesep_results(7)="tM1:   "+string(tM1);
                timesep_results(8)="tM2:   "+string(tM2);
                app.RESULTTextArea.Value=timesep_results;
            catch
                errordlg(["Error in Calculating Time Seperation!","Please Check PATH and inputting H & kappa."],'Error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Vp63Label = uilabel(app.UIFigure);
            app.Vp63Label.Text = '(Vp=6.3)';
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.kappaEditFieldLabel = uilabel(app.UIFigure);
            app.kappaEditFieldLabel.Text = 'kappa';
            app.kappaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RESULTTextAreaLabel = uilabel(app.UIFigure);
            app.RESULTTextAreaLabel.Text = 'RESULT';
            app.RESULTTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_TimeSeperationCalculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end