classdef HkcPick_hk2ttLineCalculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveFiguresButton              matlab.ui.control.Button
        LoadHkTableButton              matlab.ui.control.Button
        between2samplesLabel           matlab.ui.control.Label
        NumberofQuerypointsEditField   matlab.ui.control.NumericEditField
        NumberofQuerypointsEditFieldLabel  matlab.ui.control.Label
        UpdateButton_2                 matlab.ui.control.Button
        plotButton                     matlab.ui.control.Button
        UpdateButton                   matlab.ui.control.Button
        NumberofSamplepointsEditField  matlab.ui.control.NumericEditField
        NumberofSamplepointsEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        nodes
        query_points
        T_nodes
        save_location
    end

    methods (Access = private)

        function updatetable(app)
            app.T_nodes=table('Size',[app.nodes,2],'VariableTypes',{'double','double'});
            app.UITable.Data=app.T_nodes;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberofSamplepointsEditField.Value=2;
            app.nodes=app.NumberofSamplepointsEditField.Value;
            updatetable(app)
            app.query_points=100;
            app.NumberofQuerypointsEditField.Value=app.query_points;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if(app.NumberofSamplepointsEditField.Value>=1)
                app.nodes=round(app.NumberofSamplepointsEditField.Value);
                updatetable(app)
            else
                errordlg('Number of sample points must be greater than 1.','Error')
                return
            end
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            temp=app.UITable.Data;
            H=temp{:,1};
            kappa=temp{:,2};

            if(min(kappa)<=0.1 || min(H)<=-0.1)
                errordlg('Please check input sample points.','Error')
                return
            end

            scatter(app.UIAxes2,H,kappa,'x','k')
            xlabel(app.UIAxes2,"H")
            ylabel(app.UIAxes2,"kappa")
            ytickformat(app.UIAxes2,"%.2f")
            hold(app.UIAxes2,"on")

            for n=1:length(H)
                x(n)=app.query_points*(n-1);
            end
            xq=0:app.query_points*(length(H)-1);

            if(length(H)==2)
                H0=interp1(x,H,xq,"linear");
                kappa0=interp1(x,kappa,xq,"linear");
            elseif(length(H)>2)
                H0=interp1(x,H,xq,"spline");
                kappa0=interp1(x,kappa,xq,"spline");
            else
                errordlg('Number of sample points must be greater than 1.','Error')
                return
            end

            plot(app.UIAxes2,H0,kappa0)
            hold(app.UIAxes2,"off")

            [tPs,tM1,tM2] = HkcPick_TimeSeperation(H0,kappa0);
            plot(app.UIAxes,xq,tPs);
            hold(app.UIAxes,"on")
            plot(app.UIAxes_2,xq,tM1);
            hold(app.UIAxes_2,"on")
            plot(app.UIAxes_3,xq,tM2);
            hold(app.UIAxes_3,"on")


            [tPs0,tM10,tM20] = HkcPick_TimeSeperation(H,kappa);
            scatter(app.UIAxes,x,tPs0,'x','k')
            scatter(app.UIAxes_2,x,tM10,'x','k')
            scatter(app.UIAxes_3,x,tM20,'x','k')

            legend(app.UIAxes,'Ps','');
            legend(app.UIAxes_2,'M1','');
            legend(app.UIAxes_3,'M2','');
            grid(app.UIAxes,"on")
            grid(app.UIAxes_2,"on")
            grid(app.UIAxes_3,"on")

            xlabel(app.UIAxes,"query point index")
            ylabel(app.UIAxes,"Time Seperateion (s)")

            xlabel(app.UIAxes_2,"query point index")
            ylabel(app.UIAxes_2,"Time Seperateion (s)")

            xlabel(app.UIAxes_3,"query point index")
            ylabel(app.UIAxes_3,"Time Seperateion (s)")

            hold(app.UIAxes,"off")
            hold(app.UIAxes_2,"off")
            hold(app.UIAxes_3,"off")
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            if(app.NumberofQuerypointsEditField.Value>app.nodes)
                app.query_points=round(app.NumberofQuerypointsEditField.Value);
            else
                errordlg('The number of query points must be greater than number of sample points.','Error')
                return
            end
        end

        % Button pushed function: LoadHkTableButton
        function LoadHkTableButtonPushed(app, event)
            file=uigetfile;

            if(isnumeric(file))
                return
            end

            Hkdata=importdata(file);

            if(isnumeric(Hkdata))
                app.UITable.Data=array2table(Hkdata(:,[1 2]));
            else
                errordlg('H-k sample points must be numeric.','Error')
                return
            end
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            if(~isempty(app.save_location))
                msg = ["Save at:",app.save_location];
                title = 'Confirm Save Path';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Yes','Change Path'}, ...
                    'DefaultOption',1,'CancelOption',2);
                switch selection
                    
                    case 'Yes'
                        if(isfolder(app.save_location))
                        loc0=fullfile(app.save_location,strcat("Hk2ttLine",datestr(now,'yymmdd_HHMMss')));
                        mkdir(loc0)
                        saveas(app.UIAxes,fullfile(loc0,'Ps.png'))
                        saveas(app.UIAxes_2,fullfile(loc0,'M1.png'))
                        saveas(app.UIAxes_3,fullfile(loc0,'M2.png'))
                        saveas(app.UIAxes2,fullfile(loc0,'Hk.png'))
                        msgbox(["Figures saved at:",app.save_location]);
                        else
                            errordlg(["Could not find:",app.save_location],'Error')
                            return
                        end
                        
                    case 'Change Path'
                        app.save_location=uigerdir(pwd,'Choose a folder to save figures');
                        if(isnumeric(app.save_location))
                            return
                        end
                        app.save_location=string(app.save_location);
                        loc0=fullfile(app.save_location,strcat("Hk2ttLine",datestr(now,'yymmdd_HHMMss')));
                        mkdir(loc0)
                        saveas(app.UIAxes,fullfile(loc0,'Ps.png'))
                        saveas(app.UIAxes_2,fullfile(loc0,'M1.png'))
                        saveas(app.UIAxes_3,fullfile(loc0,'M2.png'))
                        saveas(app.UIAxes2,fullfile(loc0,'Hk.png'))
                        msgbox(["Figures saved at:",app.save_location]);
                end
            else
                
                app.save_location=uigerdir(pwd,'Choose a folder to save figures');
                if(isnumeric(app.save_location))
                    return
                end
                app.save_location=string(app.save_location);
                loc0=fullfile(app.save_location,strcat("Hk2ttLine",datestr(now,'yymmdd_HHMMss')));
                mkdir(loc0)
                saveas(app.UIAxes,fullfile(loc0,'Ps.png'))
                saveas(app.UIAxes_2,fullfile(loc0,'M1.png'))
                saveas(app.UIAxes_3,fullfile(loc0,'M2.png'))
                saveas(app.UIAxes2,fullfile(loc0,'Hk.png'))
                msgbox(["Figures saved at:",app.save_location]);
                
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 't_{Ps}')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'H-k')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 't_{M1}')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 't_{M2}')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'H'; 'kappa'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.NumberofSamplepointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSamplepointsEditFieldLabel.Text = {'Number of'; 'Sample points'};
            app.NumberofSamplepointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update ';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.UpdateButton_2 = uibutton(app.UIFigure, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update ';
            app.NumberofQuerypointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofQuerypointsEditFieldLabel.Text = {'Number of'; 'Query points'};
            app.NumberofQuerypointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.between2samplesLabel = uilabel(app.UIFigure);
            app.between2samplesLabel.Text = {'between 2 '; 'samples'};
            app.LoadHkTableButton = uibutton(app.UIFigure, 'push');
            app.LoadHkTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHkTableButtonPushed, true);
            app.LoadHkTableButton.Text = 'Load H-k Table';
            app.SaveFiguresButton = uibutton(app.UIFigure, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_hk2ttLineCalculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end