classdef HkcPick_tt2hkCalculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChangeButton             matlab.ui.control.Button
        VpEditField              matlab.ui.control.NumericEditField
        VpEditFieldLabel         matlab.ui.control.Label
        pEditField               matlab.ui.control.NumericEditField
        pEditFieldLabel          matlab.ui.control.Label
        ChangepVpCheckBox        matlab.ui.control.CheckBox
        InputPanel               matlab.ui.container.Panel
        M2CheckBox               matlab.ui.control.CheckBox
        M1CheckBox               matlab.ui.control.CheckBox
        PsCheckBox               matlab.ui.control.CheckBox
        PlotButton               matlab.ui.control.Button
        t_M2EditField            matlab.ui.control.NumericEditField
        t_M2EditFieldLabel       matlab.ui.control.Label
        t_M1EditField            matlab.ui.control.NumericEditField
        t_M1EditFieldLabel       matlab.ui.control.Label
        t_PsEditField            matlab.ui.control.NumericEditField
        t_PsEditFieldLabel       matlab.ui.control.Label
        AdjustDrawingRangePanel  matlab.ui.container.Panel
        ApplyButton              matlab.ui.control.Button
        AdjustCheckBox           matlab.ui.control.CheckBox
        k_maxEditField           matlab.ui.control.NumericEditField
        k_maxEditFieldLabel      matlab.ui.control.Label
        k_minEditField           matlab.ui.control.NumericEditField
        k_minEditFieldLabel      matlab.ui.control.Label
        H_maxEditField           matlab.ui.control.NumericEditField
        H_maxEditFieldLabel      matlab.ui.control.Label
        H_minEditField           matlab.ui.control.NumericEditField
        H_minEditFieldLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Hmin
        Hmax
        kmin
        kmax
        p
        Vp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Hmin=30;
            app.Hmax=90;
            app.kmin=1.55;
            app.kmax=2.05;
            
            app.p=0.06;
            app.Vp=6.3;

            app.H_minEditField.Value=app.Hmin;
            app.H_maxEditField.Value=app.Hmax;
            app.k_minEditField.Value=app.kmin;
            app.k_maxEditField.Value=app.kmax;
            
            app.pEditField.Value=app.p;
            app.VpEditField.Value=app.Vp;
        end

        % Value changed function: AdjustCheckBox
        function AdjustCheckBoxValueChanged(app, event)
            value = app.AdjustCheckBox.Value;
            if(value==1)
                app.k_maxEditField.Enable='on';
                app.k_minEditField.Enable='on';
                app.H_maxEditField.Enable='on';
                app.H_minEditField.Enable='on';
                app.ApplyButton.Enable='on';
            else
                app.k_maxEditField.Enable='off';
                app.k_minEditField.Enable='off';
                app.H_maxEditField.Enable='off';
                app.H_minEditField.Enable='off';
                app.ApplyButton.Enable='off';
            end
        end

        % Value changed function: PsCheckBox
        function PsCheckBoxValueChanged(app, event)
            value = app.PsCheckBox.Value;
            if(value==0)
                app.t_PsEditField.Enable='off';
            else
                app.t_PsEditField.Enable='on';
            end
        end

        % Value changed function: M1CheckBox
        function M1CheckBoxValueChanged(app, event)
            value = app.M1CheckBox.Value;
            if(value==0)
                app.t_M1EditField.Enable='off';
            else
                app.t_M1EditField.Enable='on';
            end
        end

        % Value changed function: M2CheckBox
        function M2CheckBoxValueChanged(app, event)
            value = app.M2CheckBox.Value;
            if(value==0)
                app.t_M2EditField.Enable='off';
            else
                app.t_M2EditField.Enable='on';
            end
        end

        % Value changed function: ChangepVpCheckBox
        function ChangepVpCheckBoxValueChanged(app, event)
            value = app.ChangepVpCheckBox.Value;
            if(value==1)
                app.pEditField.Enable='on';
                app.VpEditField.Enable='on';
                app.ChangeButton.Enable='on';
            else
                app.pEditField.Enable='off';
                app.VpEditField.Enable='off';
                app.ChangeButton.Enable='off';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.Hmax=app.H_maxEditField.Value;
            app.Hmin=app.H_minEditField.Value;
            app.kmax=app.k_maxEditField.Value;
            app.kmin=app.k_minEditField.Value;
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.p=app.pEditField.Value;
            app.Vp=app.VpEditField.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            hold(app.UIAxes,"off")
            
            k=(app.kmin):0.005:(app.kmax);
            Vs=(app.Vp)./k;
            
            eta_p=sqrt(1/(app.Vp)^2-app.p^2);
            eta_s=sqrt(1./Vs.^2-app.p^2);
            
            if(app.PsCheckBox.Value)
                tPs=app.t_PsEditField.Value;
                H1=tPs./(eta_s-eta_p);
                plot(app.UIAxes,H1,k,'DisplayName','Ps')
                hold(app.UIAxes,"on")
            end
            if(app.M1CheckBox.Value)
                tM1=app.t_M1EditField.Value;
                H2=tM1./(eta_s+eta_p);
                plot(app.UIAxes,H2,k,'DisplayName','M1')
                hold(app.UIAxes,"on")
            end
            if(app.M2CheckBox.Value)
                tM2=app.t_M2EditField.Value;
                H3=tM2./(2*eta_s);
                plot(app.UIAxes,H3,k,'DisplayName','M2')
                hold(app.UIAxes,"on")
            end
            
            legend(app.UIAxes)
            axis(app.UIAxes,[app.Hmin app.Hmax app.kmin app.kmax])
            xticks(app.UIAxes,app.Hmin:5:app.Hmax)
            yticks(app.UIAxes,app.kmin:0.05:app.kmax)
            ytickformat(app.UIAxes,'%.2f')
            xlabel(app.UIAxes,"H")
            ylabel(app.UIAxes,"kappa")
            grid(app.UIAxes,"on")
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AdjustDrawingRangePanel = uipanel(app.UIFigure);
            app.AdjustDrawingRangePanel.Title = 'Adjust Drawing Range';
            app.H_minEditFieldLabel = uilabel(app.AdjustDrawingRangePanel);
            app.H_minEditFieldLabel.Text = 'H_min';
            app.H_minEditField = uieditfield(app.AdjustDrawingRangePanel, 'numeric');
            app.H_minEditField.Enable = 'off';
            app.H_maxEditFieldLabel = uilabel(app.AdjustDrawingRangePanel);
            app.H_maxEditFieldLabel.Text = 'H_max';
            app.H_maxEditField = uieditfield(app.AdjustDrawingRangePanel, 'numeric');
            app.H_maxEditField.Enable = 'off';
            app.k_minEditFieldLabel = uilabel(app.AdjustDrawingRangePanel);
            app.k_minEditFieldLabel.Text = 'k_min';
            app.k_minEditField = uieditfield(app.AdjustDrawingRangePanel, 'numeric');
            app.k_minEditField.Enable = 'off';
            app.k_maxEditFieldLabel = uilabel(app.AdjustDrawingRangePanel);
            app.k_maxEditFieldLabel.Text = 'k_max';
            app.k_maxEditField = uieditfield(app.AdjustDrawingRangePanel, 'numeric');
            app.k_maxEditField.Enable = 'off';
            app.AdjustCheckBox = uicheckbox(app.AdjustDrawingRangePanel);
            app.AdjustCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustCheckBoxValueChanged, true);
            app.AdjustCheckBox.Text = 'Adjust';
            app.ApplyButton = uibutton(app.AdjustDrawingRangePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.t_PsEditFieldLabel = uilabel(app.InputPanel);
            app.t_PsEditFieldLabel.Text = 't_Ps';
            app.t_PsEditField = uieditfield(app.InputPanel, 'numeric');
            app.t_M1EditFieldLabel = uilabel(app.InputPanel);
            app.t_M1EditFieldLabel.Text = 't_M1';
            app.t_M1EditField = uieditfield(app.InputPanel, 'numeric');
            app.t_M2EditFieldLabel = uilabel(app.InputPanel);
            app.t_M2EditFieldLabel.Text = 't_M2';
            app.t_M2EditField = uieditfield(app.InputPanel, 'numeric');
            app.PlotButton = uibutton(app.InputPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PsCheckBox = uicheckbox(app.InputPanel);
            app.PsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PsCheckBoxValueChanged, true);
            app.PsCheckBox.Text = 'Ps';
            app.PsCheckBox.Value = true;
            app.M1CheckBox = uicheckbox(app.InputPanel);
            app.M1CheckBox.ValueChangedFcn = createCallbackFcn(app, @M1CheckBoxValueChanged, true);
            app.M1CheckBox.Text = 'M1';
            app.M1CheckBox.Value = true;
            app.M2CheckBox = uicheckbox(app.InputPanel);
            app.M2CheckBox.ValueChangedFcn = createCallbackFcn(app, @M2CheckBoxValueChanged, true);
            app.M2CheckBox.Text = 'M2';
            app.M2CheckBox.Value = true;
            app.ChangepVpCheckBox = uicheckbox(app.UIFigure);
            app.ChangepVpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangepVpCheckBoxValueChanged, true);
            app.ChangepVpCheckBox.Text = 'Change p/Vp:';
            app.pEditFieldLabel = uilabel(app.UIFigure);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.UIFigure, 'numeric');
            app.pEditField.Enable = 'off';
            app.VpEditFieldLabel = uilabel(app.UIFigure);
            app.VpEditFieldLabel.Text = 'Vp';
            app.VpEditField = uieditfield(app.UIFigure, 'numeric');
            app.VpEditField.Enable = 'off';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Enable = 'off';
            app.ChangeButton.Text = 'Change';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_tt2hkCalculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end