classdef HkcPick_Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        pleaseselectalltherequireddirectoriesbeforestartingLabel  matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        StatusReportTextArea          matlab.ui.control.TextArea
        StatusReportLabel             matlab.ui.control.Label
        BrowseButton_3                matlab.ui.control.Button
        BrowseButton_2                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        NewHkcResultsDirectoryEditField  matlab.ui.control.EditField
        NewHkcResultsDirectoryLabel   matlab.ui.control.Label
        HkcPickDirectoryEditField     matlab.ui.control.EditField
        HkcPickDirectoryLabel         matlab.ui.control.Label
        HkcResultsDirectoryEditField  matlab.ui.control.EditField
        HkcResultsDirectoryLabel      matlab.ui.control.Label
        HkcPickStartGuideLabel        matlab.ui.control.Label
        SelectJobTypeButtonGroup      matlab.ui.container.ButtonGroup
        QuickReviewButton             matlab.ui.control.ToggleButton
        ChangeFinishedPickJobButton   matlab.ui.control.ToggleButton
        ContinueUnfinishedPickJobButton  matlab.ui.control.ToggleButton
        CreatNewHkcPickJobButton      matlab.ui.control.ToggleButton
    end


    properties (Access = public)
        Hkc_init_data_dir % Hkc Results with no picking
        Hkc_pick_dir      % Picks for Hkc Results
        Hkc_new_data_dir  % New Hkc Results for existing Hkc Picks
    end

    properties (Access = private)
        status_report      % Report of Initializing Status
        status             % Logical variable for input completeness
        NextApp            % Next app to call
    end


    methods (Access = private)

        function ChooseJobType(app,jobtype)
            %Initialize Conditions
            app.BrowseButton.Enable='off';
            app.HkcResultsDirectoryEditField.Enable='off';
            app.BrowseButton_2.Enable='off';
            app.HkcPickDirectoryEditField.Enable='off';
            app.BrowseButton_3.Enable='off';
            app.NewHkcResultsDirectoryEditField.Enable='off';
            %Apply New Conditions according to Jobtype
            switch jobtype
                case "Creat New Hkc Pick Job"
                    app.BrowseButton.Enable='on';
                    app.HkcResultsDirectoryEditField.Enable='on';
                case "Continue Unfinished Pick Job"
                    app.BrowseButton.Enable='on';
                    app.HkcResultsDirectoryEditField.Enable='on';
                    app.BrowseButton_2.Enable='on';
                    app.HkcPickDirectoryEditField.Enable='on';
                case "Change Finished Pick Job"
                    app.BrowseButton_2.Enable='on';
                    app.HkcPickDirectoryEditField.Enable='on';
                    app.BrowseButton_3.Enable='on';
                    app.NewHkcResultsDirectoryEditField.Enable='on';
                case "Quick Review"
                    app.BrowseButton_2.Enable='on';
                    app.HkcPickDirectoryEditField.Enable='on';
                otherwise
                    errordlg("Unexpected Job Type",'Error Message','error')
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Add Path
            run('HkcPick_AddPath.m')
            %Initialize status_report and status
            app.status_report=strings(20,1);
            app.status=false;
            %Reporting Issue Prompt
            app.status_report(1)="Job Type:";
            %Show Default Selected Button Condition
            selectedButton = app.SelectJobTypeButtonGroup.SelectedObject;
            app.status_report(2)=string(selectedButton.Text);
            app.StatusReportTextArea.Value=app.status_report;
            %Apply New Conditions according to Jobtype(i.e. app.status_report)
            app.ChooseJobType(selectedButton.Text)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %Get Initial Data Directory
            app.Hkc_init_data_dir=uigetdir(pwd,'Select Hkc Results Directory');
            app.HkcResultsDirectoryEditField.Value=string(app.Hkc_init_data_dir);
            %Update Status Reports
            app.status_report(3)="Initial Hkc Results Directory:";
            app.status_report(4)=string(app.Hkc_init_data_dir);
            app.StatusReportTextArea.Value=app.status_report;

            %Start to Check Input
            dircheck=dir(app.Hkc_init_data_dir);
            count=0;
            [l,~]=size(dircheck);
            for n=1:l
                if(dircheck(n).name ~= "." && dircheck(n).name ~= "..")
                    onecheck=fullfile(dircheck(n).folder,dircheck(n).name);
                    if(isfolder(onecheck))
                        count=count+1;
                    else
                        datainfo=onecheck;
                    end
                end
            end
            %Read datainfo file
            try
                lines=readlines(datainfo,"EmptyLineRule","skip");
                %Update Status Reports
                [l,~]=size(lines);
                app.status_report(5)=string(strcat(num2str(count)," subdirectories, ",num2str(l)," records in *_Raw.txt are discorvered!"));
                app.StatusReportTextArea.Value=app.status_report;
                %check passes
                app.status=true;
            catch
                errordlg("Error in reading *_Raw.txt file!",'Error Message','error')
            end
        end

        % Selection changed function: SelectJobTypeButtonGroup
        function SelectJobTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectJobTypeButtonGroup.SelectedObject;
            app.status_report(2)=string(selectedButton.Text);
            %assignin("base","test",app.status_report)
            app.StatusReportTextArea.Value=app.status_report;
            %Apply New Conditions according to Jobtype(i.e. app.status_report)
            ChooseJobType(app,selectedButton.Text)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.status)
                %Check passed, run Hkc Pick
                selectedButton = app.SelectJobTypeButtonGroup.SelectedObject;
                jobtype=selectedButton.Text;
                switch jobtype
                    case "Creat New Hkc Pick Job"
                        try
                            %run ./HkcPickPrograms/HkcPick_1NewPick.mlapp
                            app.NextApp=HkcPick_1NewPick(app.Hkc_init_data_dir);
                            delete(app.UIFigure);
                            return
                        catch
                            errordlg(["Either: (1) Matlab current folder (pwd) isn't the absolute path of Hkc_PICK!",...
                                strcat("CurrentDir: ",pwd),"Or: (2) HkcPick_1NewPick didn't start correctly!"],'Error Message','error')
                        end
                    case "Continue Unfinished Pick Job"
                        try
                            app.NextApp=HkcPick_2ContinuePick(app.Hkc_init_data_dir,app.Hkc_pick_dir);
                            delete(app.UIFigure);
                            return
                        catch
                            errordlg(["Either: (1) Matlab current folder (pwd) isn't the absolute path of Hkc_PICK!",...
                                strcat("CurrentDir: ",pwd),"Or: (2) HkcPick_2ContinuePick didn't start correctly!"],'Error Message','error')
                        end
                    case "Change Finished Pick Job"
                        try
                            app.NextApp=HkcPick_3ReplacePick(app.Hkc_new_data_dir,app.Hkc_pick_dir);
                            delete(app.UIFigure);
                            return
                        catch
                            errordlg(["Either: (1) Matlab current folder (pwd) isn't the absolute path of Hkc_PICK!",...
                                strcat("CurrentDir: ",pwd),"Or: (2) HkcPick_3ReplacePick didn't start correctly!"],'Error Message','error')
                        end
                    case "Quick Review"
                        try
                            app.NextApp=HkcPick_4QuickReview(app.Hkc_pick_dir);
                            delete(app.UIFigure);
                            return
                        catch
                            errordlg(["Either: (1) Matlab current folder (pwd) isn't the absolute path of Hkc_PICK!",...
                                strcat("CurrentDir: ",pwd),"Or: (2) HkcPick_4QuickReview didn't start correctly!"],'Error Message','error')
                        end
                    otherwise
                        errordlg("Unexpected Job Type",'Error Message','error')
                end
            else
                app.pleaseselectalltherequireddirectoriesbeforestartingLabel.Visible='on';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    STARTButtonPushed(app);
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            %Get Initial Data Directory
            app.Hkc_pick_dir=uigetdir(pwd,'Select Hkc Pick Directory');
            app.HkcPickDirectoryEditField.Value=string(app.Hkc_pick_dir);
            %Update Status Reports
            app.status_report(6)="Hkc Pick Directory:";
            app.status_report(7)=string(app.Hkc_pick_dir);
            app.StatusReportTextArea.Value=app.status_report;
            %Update Status
            if(string(app.SelectJobTypeButtonGroup.SelectedObject.Text)=="Quick Review")
                %check passes
                app.status=true;
            end
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            %Get New Data Directory
            app.Hkc_new_data_dir=uigetdir(pwd,'Select Hkc New Results Directory');
            app.NewHkcResultsDirectoryEditField.Value=string(app.Hkc_new_data_dir);
            %Update Status Reports
            app.status_report(3)="New Hkc Results Directory:";
            app.status_report(4)=string(app.Hkc_new_data_dir);
            app.StatusReportTextArea.Value=app.status_report;

            %Start to Check Input
            dircheck=dir(app.Hkc_new_data_dir);
            count=0;
            [l,~]=size(dircheck);
            for n=1:l
                if(dircheck(n).name ~= "." && dircheck(n).name ~= "..")
                    onecheck=fullfile(dircheck(n).folder,dircheck(n).name);
                    if(isfolder(onecheck))
                        count=count+1;
                    else
                        datainfo=onecheck;
                    end
                end
            end
            %Read datainfo file
            try
                lines=readlines(datainfo,"EmptyLineRule","skip");
                %Update Status Reports
                [l,~]=size(lines);
                app.status_report(5)=string(strcat(num2str(count)," subdirectories, ",num2str(l)," records in *_Raw.txt are discorvered!"));
                app.StatusReportTextArea.Value=app.status_report;
                %check passes
                app.status=true;
            catch
                errordlg("Error in reading *_Raw.txt file!",'Error Message','error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SelectJobTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectJobTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectJobTypeButtonGroupSelectionChanged, true);
            app.SelectJobTypeButtonGroup.Title = 'Select Job Type';
            app.CreatNewHkcPickJobButton = uitogglebutton(app.SelectJobTypeButtonGroup);
            app.CreatNewHkcPickJobButton.Text = 'Creat New Hkc Pick Job';
            app.CreatNewHkcPickJobButton.Value = true;
            app.ContinueUnfinishedPickJobButton = uitogglebutton(app.SelectJobTypeButtonGroup);
            app.ContinueUnfinishedPickJobButton.Text = 'Continue Unfinished Pick Job';
            app.ChangeFinishedPickJobButton = uitogglebutton(app.SelectJobTypeButtonGroup);
            app.ChangeFinishedPickJobButton.Text = 'Change Finished Pick Job';
            app.QuickReviewButton = uitogglebutton(app.SelectJobTypeButtonGroup);
            app.QuickReviewButton.Text = 'Quick Review';
            app.HkcPickStartGuideLabel = uilabel(app.UIFigure);
            app.HkcPickStartGuideLabel.Text = 'Hkc-Pick Start Guide';
            app.HkcResultsDirectoryLabel = uilabel(app.UIFigure);
            app.HkcResultsDirectoryLabel.Text = {'Hkc Results'; 'Directory'};
            app.HkcResultsDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.HkcResultsDirectoryEditField.Enable = 'off';
            app.HkcPickDirectoryLabel = uilabel(app.UIFigure);
            app.HkcPickDirectoryLabel.Text = {'Hkc Pick'; 'Directory'};
            app.HkcPickDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.HkcPickDirectoryEditField.Enable = 'off';
            app.NewHkcResultsDirectoryLabel = uilabel(app.UIFigure);
            app.NewHkcResultsDirectoryLabel.Text = {'New Hkc Results'; 'Directory'};
            app.NewHkcResultsDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.NewHkcResultsDirectoryEditField.Enable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.BrowseButton_3 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Enable = 'off';
            app.BrowseButton_3.Text = 'Browse';
            app.StatusReportLabel = uilabel(app.UIFigure);
            app.StatusReportLabel.Text = 'Status Report:';
            app.StatusReportTextArea = uitextarea(app.UIFigure);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.pleaseselectalltherequireddirectoriesbeforestartingLabel = uilabel(app.UIFigure);
            app.pleaseselectalltherequireddirectoriesbeforestartingLabel.Visible = 'off';
            app.pleaseselectalltherequireddirectoriesbeforestartingLabel.Text = 'please select all the required directories before starting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkcPick_Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end